classdef MasterdocFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Sample1Panel                    matlab.ui.container.Panel
        LoadButton_1                    matlab.ui.control.Button
        PlayButton_1                    matlab.ui.control.Button
        VolumeKnobLabel                 matlab.ui.control.Label
        VolumeKnob_1                    matlab.ui.control.Knob
        PauseButton_1                   matlab.ui.control.Button
        UIAxes2_1                       matlab.ui.control.UIAxes
        Lamp_1                          matlab.ui.control.Lamp
        Sample2Panel                    matlab.ui.container.Panel
        LoadButton_2                    matlab.ui.control.Button
        PlayButton_2                    matlab.ui.control.Button
        VolumeKnob_10Label              matlab.ui.control.Label
        VolumeKnob_2                    matlab.ui.control.Knob
        PauseButton_2                   matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        Lamp_2                          matlab.ui.control.Lamp
        Sample3Panel                    matlab.ui.container.Panel
        LoadButton_3                    matlab.ui.control.Button
        PlayButton_3                    matlab.ui.control.Button
        VolumeKnob_11Label              matlab.ui.control.Label
        VolumeKnob_3                    matlab.ui.control.Knob
        PauseButton_3                   matlab.ui.control.Button
        UIAxes2_3                       matlab.ui.control.UIAxes
        Lamp_3                          matlab.ui.control.Lamp
        Sample4Panel                    matlab.ui.container.Panel
        LoadButton_4                    matlab.ui.control.Button
        PlayButton_4                    matlab.ui.control.Button
        VolumeKnob_12Label              matlab.ui.control.Label
        VolumeKnob_4                    matlab.ui.control.Knob
        PauseButton_4                   matlab.ui.control.Button
        UIAxes2_4                       matlab.ui.control.UIAxes
        Lamp_4                          matlab.ui.control.Lamp
        Sample5Panel                    matlab.ui.container.Panel
        LoadButton_5                    matlab.ui.control.Button
        PlayButton_5                    matlab.ui.control.Button
        VolumeKnob_13Label              matlab.ui.control.Label
        VolumeKnob_5                    matlab.ui.control.Knob
        PauseButton_5                   matlab.ui.control.Button
        UIAxes2_5                       matlab.ui.control.UIAxes
        Lamp_5                          matlab.ui.control.Lamp
        Sample6Panel                    matlab.ui.container.Panel
        LoadButton_6                    matlab.ui.control.Button
        PlayButton_6                    matlab.ui.control.Button
        VolumeKnob_14Label              matlab.ui.control.Label
        VolumeKnob_6                    matlab.ui.control.Knob
        PauseButton_6                   matlab.ui.control.Button
        UIAxes2_6                       matlab.ui.control.UIAxes
        Lamp_6                          matlab.ui.control.Lamp
        Sample7Panel                    matlab.ui.container.Panel
        LoadButton_7                    matlab.ui.control.Button
        PlayButton_7                    matlab.ui.control.Button
        VolumeKnob_15Label              matlab.ui.control.Label
        VolumeKnob_7                    matlab.ui.control.Knob
        PauseButton_7                   matlab.ui.control.Button
        UIAxes2_7                       matlab.ui.control.UIAxes
        Lamp_7                          matlab.ui.control.Lamp
        Sample8Panel                    matlab.ui.container.Panel
        LoadButton_8                    matlab.ui.control.Button
        PlayButton_8                    matlab.ui.control.Button
        VolumeKnob_16Label              matlab.ui.control.Label
        VolumeKnob_8                    matlab.ui.control.Knob
        PauseButton_8                   matlab.ui.control.Button
        UIAxes2_8                       matlab.ui.control.UIAxes
        Lamp_8                          matlab.ui.control.Lamp
        Sample9Panel                    matlab.ui.container.Panel
        LoadButton_9                    matlab.ui.control.Button
        PlayButton_9                    matlab.ui.control.Button
        VolumeKnob_17Label              matlab.ui.control.Label
        VolumeKnob_9                    matlab.ui.control.Knob
        PauseButton_9                   matlab.ui.control.Button
        UIAxes2_9                       matlab.ui.control.UIAxes
        Lamp_9                          matlab.ui.control.Lamp
        SystemControlPanel              matlab.ui.container.Panel
        UIAxes_System                   matlab.ui.control.UIAxes
        PlayButton_System               matlab.ui.control.Button
        PauseButton_System              matlab.ui.control.Button
        RecordButton_System             matlab.ui.control.Button
        ClipSlider2                     matlab.ui.control.Slider
        StopRecordButton_System         matlab.ui.control.Button
        SaveSampleButton_System         matlab.ui.control.Button
        Lamp_System                     matlab.ui.control.Lamp
        VolumeKnob_18Label              matlab.ui.control.Label
        VolumeKnob_System               matlab.ui.control.Knob
        Label                           matlab.ui.control.Label
        Switch_Direction                matlab.ui.control.ToggleSwitch
        Switch_2Label                   matlab.ui.control.Label
        Switch_Stereo                   matlab.ui.control.ToggleSwitch
        HzLabel                         matlab.ui.control.Label
        EqSlider_32                     matlab.ui.control.Slider
        HzLabel_2                       matlab.ui.control.Label
        EqSlider_64                     matlab.ui.control.Slider
        HzLabel_3                       matlab.ui.control.Label
        EqSlider_125                    matlab.ui.control.Slider
        HzLabel_4                       matlab.ui.control.Label
        EqSlider_250                    matlab.ui.control.Slider
        HzLabel_5                       matlab.ui.control.Label
        EqSlider_500                    matlab.ui.control.Slider
        kHzLabel                        matlab.ui.control.Label
        EqSlider_1k                     matlab.ui.control.Slider
        kHzLabel_2                      matlab.ui.control.Label
        EqSlider_2k                     matlab.ui.control.Slider
        kHzLabel_3                      matlab.ui.control.Label
        EqSlider_4k                     matlab.ui.control.Slider
        kHzLabel_4                      matlab.ui.control.Label
        EqSlider_8k                     matlab.ui.control.Slider
        kHzLabel_5                      matlab.ui.control.Label
        EqSlider_16k                    matlab.ui.control.Slider
        LoadSampleDropDownLabel         matlab.ui.control.Label
        LoadSampleDropDown              matlab.ui.control.DropDown
        SaveToDropDown_2Label           matlab.ui.control.Label
        SaveToDropDown_2                matlab.ui.control.DropDown
        SpeedSliderLabel                matlab.ui.control.Label
        SpeedSlider                     matlab.ui.control.Slider
        DelaySliderLabel                matlab.ui.control.Label
        DelaySlider                     matlab.ui.control.Slider
        ClipSliderLabel                 matlab.ui.control.Label
        ClipSlider1                     matlab.ui.control.Slider
        EchoSlider_2Label               matlab.ui.control.Label
        EchoSlider_2                    matlab.ui.control.Slider
        DisplayTextAreaLabel            matlab.ui.control.Label
        DisplayTextArea                 matlab.ui.control.TextArea
        MixingPanel                     matlab.ui.container.Panel
        Track1Slider                    matlab.ui.control.Slider
        Track2Slider                    matlab.ui.control.Slider
        Track3Slider                    matlab.ui.control.Slider
        Track4Slider                    matlab.ui.control.Slider
        Track5Slider                    matlab.ui.control.Slider
        Track6Slider                    matlab.ui.control.Slider
        Track7Slider                    matlab.ui.control.Slider
        Track8Slider                    matlab.ui.control.Slider
        Track1DropDownLabel             matlab.ui.control.Label
        Track1DropDown                  matlab.ui.control.DropDown
        Track2DropDownLabel             matlab.ui.control.Label
        Track2DropDown                  matlab.ui.control.DropDown
        Track3DropDownLabel             matlab.ui.control.Label
        Track3DropDown                  matlab.ui.control.DropDown
        Track4DropDownLabel             matlab.ui.control.Label
        Track4DropDown                  matlab.ui.control.DropDown
        Track5DropDownLabel             matlab.ui.control.Label
        Track5DropDown                  matlab.ui.control.DropDown
        Track6DropDownLabel             matlab.ui.control.Label
        Track6DropDown                  matlab.ui.control.DropDown
        Track7DropDownLabel             matlab.ui.control.Label
        Track7DropDown                  matlab.ui.control.DropDown
        Track8DropDownLabel             matlab.ui.control.Label
        Track8DropDown                  matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        PlayButton_Mixer                matlab.ui.control.Button
        PauseButton_Mixer               matlab.ui.control.Button
        RecordButton_Mixer              matlab.ui.control.Button
        SaveToDropDownLabel             matlab.ui.control.Label
        SaveToDropDown_Mixer            matlab.ui.control.DropDown
        Lamp_Mixer                      matlab.ui.control.Lamp
        StopRecordButton_Mixer          matlab.ui.control.Button
        SaveSampleButton_Mixer          matlab.ui.control.Button
        SampleLengthminsEditFieldLabel  matlab.ui.control.Label
        SampleLengthminsEditField       matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel        matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.NumericEditField
        SynthesizerPanel                matlab.ui.container.Panel
        UIAxes19                        matlab.ui.control.UIAxes
        WaveListBoxLabel                matlab.ui.control.Label
        WaveListBox                     matlab.ui.control.ListBox
        SaveToDropDown_3Label           matlab.ui.control.Label
        SaveToDropDown_3                matlab.ui.control.DropDown
        CButton                         matlab.ui.control.Button
        DButton                         matlab.ui.control.Button
        FButton                         matlab.ui.control.Button
        GButton                         matlab.ui.control.Button
        EButton                         matlab.ui.control.Button
        AButton                         matlab.ui.control.Button
        BButton                         matlab.ui.control.Button
        CSharpButton                    matlab.ui.control.Button
        DSharpButton                    matlab.ui.control.Button
        FSharpButton                    matlab.ui.control.Button
        GSharpButton                    matlab.ui.control.Button
        ASharpButton                    matlab.ui.control.Button
        SaveSampleButton_Synth          matlab.ui.control.Button
    end

    
    properties (Access = private)
        sample = cell(14);            % Cell array to hold audio information of 9 samples, 1 system sample, and 4 temp samples
        permSample = cell(10);        % Cell array to hold the audio data without manipulation
        sampleRate = cell(10);        % Cell array to hold sample rate of samples
        permRate = cell(10);          % Cell array to hold permanent sample rates
        player = cell(10);            % Cell array of audioplayer objects
        pauseFlag = zeros(11);        % Array of pause status of each player
        equalizerSample = cell(10);   % Cell array to hold audio data in each frequency band of equalizer
        freqSample;                   % Sample in frequency domain
        freq;                         % Frequency vector
        startRecord;                  % Keeps track of a location to start recording in the system
        stopRecord;                   % Keeps track of a location to stop recording in the system
        startRecord2;                 % Keeps track of a location to start recording in the mixer
        stopRecord2;                  % Keeps track of a location to stop recording in the mixer
        trackLocation;                % Keeps track of sample location to allow a new player to be created
        
    end
    
    methods (Access = private)
        
        function [] = PlayLight(app, number, color)        % Function to change lamp color of individual play lamps
            switch number
                case 1
                    app.Lamp_1.Color = color;
                case 2
                    app.Lamp_2.Color = color;
                case 3
                    app.Lamp_3.Color = color;
                case 4
                    app.Lamp_4.Color = color;
                case 5
                    app.Lamp_5.Color = color;
                case 6
                    app.Lamp_6.Color = color;
                case 7
                    app.Lamp_7.Color = color;
                case 8
                    app.Lamp_8.Color = color;
                otherwise
                    app.Lamp_9.Color = color;
            end


        end
        
        function [] = SetGraph(app, number)              % Function to change plot individual waveforms
            thisSample = app.sample{number};
            switch number
                case 1
                    plot(app.UIAxes2_1, thisSample);
                case 2
                    plot(app.UIAxes2_2, thisSample);
                case 3
                    plot(app.UIAxes2_3, thisSample);
                case 4
                    plot(app.UIAxes2_4, thisSample);
                case 5
                    plot(app.UIAxes2_5, thisSample);
                case 6
                    plot(app.UIAxes2_6, thisSample);
                case 7
                    plot(app.UIAxes2_7, thisSample);
                case 8
                    plot(app.UIAxes2_8, thisSample);
                case 9
                    plot(app.UIAxes2_9, thisSample);
                otherwise
                    app.DisplayTextArea.Value = 'Not a valid location';
                    pause(3);
                    app.DisplayTextArea.Value = '';
            end
            
        end
        
        function [] = SaveSample(app, number, sample, rate)                 % Function to save a playing sample to a sample location
            if (number > 9)
                app.DisplayTextArea.Value = 'Not a valid location';
                pause (3)
                app.DisplayTextArea.VAlue = '';
                
            else
                
                app.sample{number} = sample;                                    % Creates a sample audio array
                app.permSample{number} = sample;                                % Creates permanent sample
                app.sampleRate{number} = rate;                                  % Creates sample rate
                app.permRate{number} = rate;                                    % Creates permanent rate
                app.player{number} = audioplayer(sample,rate);                  % Creates audioplayer object
                app.SetGraph(number);                                           % Plots waveform on indivdual display
                app.PlayLight(number, 'red');                                   % Sets individual status light
            end
        end
        
        function modtrack = AddTrack(app,oldTrack,newTrack,location)        % Function to add a sample into an existing track at a specified location
            [m,n] = size(newTrack);
            
            if (m~=1)                                                       % Short circuits function if input sample is empty
                
                start = int32(location - floor(m/2));                           % Sets start and stop location to insert new sample
                stop = int32(start + m-1);
                if (start < 1)                                                  % Error handling if sample is placed too early in track, relocates sample to start of track
                    app.DisplayTextArea.Value = 'Sample located before track start';
                    pause(3);
                    app.DisplayTextArea.Value = '';
                    start = 1;
                    stop = m;
                end
                
                if (stop > size(oldTrack(:,1)))                                 % Error handling if sample is placed too late in track, relocates sample to end of track
                    app.DisplayTextArea.Value = 'Sample located after track end';
                    pause(3);
                    app.DisplayTextArea.Value = '';
                    stop = size(oldTrack(:,1));
                    start = size(oldTrack(:,1)) - m +1;
                    
                end
                
                if (n==1)                                                       % For a mono sample, overwrites new track into old track at desired location
                    oldTrack(start:stop,1) = newTrack;
                    
                elseif (n==2)                                                   % For a stereo sample, overwrites new track into old track at desired location
                    oldTrack(start:stop,1) = newTrack(:,1);
                    oldTrack(start:stop,2) = newTrack(:,2);
                else
                    app.DisplayTextArea.Value = 'Number of channels should be 1 or 2';  % Error handling if unexpected number of channels
                    pause(3);
                    app.DisplayTextArea.Value = '';
                end
            end
            
            modtrack = oldTrack;            % Returns modified track with inserted sample
            
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Initialization of samples is necessary to prevent error if
            % user selects an option accessing an object that does not
            % exist
            
            for i = 1:14
                app.sample{i} = 0;                % Initializes all samples to 0
                app.permSample{i} = 0;            % Initializes all permanent samples
                app.sampleRate{i} = 1000;         % Initializes sample rate to minimum rate
                app.player{i}=audioplayer(app.sample{i}, app.sampleRate{i});   %#ok<TNMLP>  %Initializes all player objects
            end
            
            app.VolumeKnob_1.Value =50;     % Sets all necessary system values
            app.VolumeKnob_2.Value =50;
            app.VolumeKnob_3.Value =50;
            app.VolumeKnob_4.Value =50;
            app.VolumeKnob_5.Value =50;
            app.VolumeKnob_6.Value =50;
            app.VolumeKnob_7.Value =50;
            app.VolumeKnob_8.Value =50;
            app.VolumeKnob_9.Value =50;
            
            app.Lamp_1.Color = 'black';     % Set default lamp status
            app.Lamp_2.Color = 'black';
            app.Lamp_3.Color = 'black';
            app.Lamp_4.Color = 'black';
            app.Lamp_5.Color = 'black';
            app.Lamp_6.Color = 'black';
            app.Lamp_7.Color = 'black';
            app.Lamp_8.Color = 'black';
            app.Lamp_9.Color = 'black';
            app.Lamp_System.Color = 'black';
            app.Lamp_Mixer.Color = 'black';
            
            app.EqSlider_32.Value = 0;
            app.EqSlider_64.Value = 0;
            app.EqSlider_125.Value = 0;
            app.EqSlider_250.Value = 0;
            app.EqSlider_500.Value = 0;
            app.EqSlider_1k.Value = 0;
            app.EqSlider_2k.Value = 0;
            app.EqSlider_4k.Value = 0;
            app.EqSlider_8k.Value = 0;
            app.EqSlider_16k.Value = 0;
            
            app.VolumeKnob_System.Value = 50;
            app.Switch_Direction.Value = 'Forward';
            app.Switch_Stereo.Value = 'Stereo';
            app.SpeedSlider.Value = 1;
            app.ClipSlider1.Value = 1;
            app.ClipSlider2.Value = 100;
            app.startRecord = 0;
            app.startRecord2 = 0;
            
            
            app.Track1DropDown.Value = 'Select Sample';
            app.Track2DropDown.Value = 'Select Sample';
            app.Track3DropDown.Value = 'Select Sample';
            app.Track4DropDown.Value = 'Select Sample';
            app.Track5DropDown.Value = 'Select Sample';
            app.Track6DropDown.Value = 'Select Sample';
            app.Track7DropDown.Value = 'Select Sample';
            app.Track8DropDown.Value = 'Select Sample';
            
            app.DisplayTextArea.Value = '      Welcome to Audio Sampler 6:       Chris Hopp Aashay Vartak Jinxuan Feng  ENG6 SSII 2020';
            pause(12)       
            app.DisplayTextArea.Value = 'Please load a .wav file to begin';
        end

        % Button pushed function: LoadButton_1
        function LoadButton_1Pushed(app, event)
            
            [filename,filepath] = uigetfile('*.wav');                       % Gets .wav file from user input
            file = strcat(filepath,filename);                               % Creates a full pathname
            [~, ~, extension] = fileparts(file);
            if isempty(file)                                                % Error handling if user cancels file load
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            elseif (strcmpi(extension, '.wav') == false)                    % Error handling if user selects wrong file type
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            else
                [app.permSample{1},app.permRate{1}] = audioread(file);        % Stores audio and rate data to permanent files
                app.sample{1} = app.permSample{1};                              % Creates a sample audio array
                app.sampleRate{1} = app.permRate{1};                            % Creates sample rate
                app.player{1} = audioplayer(app.sample{1},app.sampleRate{1});   % Creates audioplayer object
                plot(app.UIAxes2_1,app.sample{1});                              % Plots waveform on indivdual display
                app.Lamp_1.Color = 'red';                                       % Sets individual status light
                app.DisplayTextArea.Value = '';
            end
            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            [filename, filepath] = uigetfile('*.wav');                      % Gets .wav file from user input
            file = strcat(filepath,filename);                               % Creates a full pathname
            [~, ~, extension] = fileparts(file);
            if isempty(file)                                                % Error handling if user cancels file load
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            elseif (strcmpi(extension, '.wav') == false)                    % Error handling if user selects wrong file type
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            else
            [app.permSample{2},app.permRate{2}] = audioread(file);        % Stores audio and rate data to permanent files
            app.sample{2} = app.permSample{2};                              % Creates a sample audio array
            app.sampleRate{2} = app.permRate{2};                            % Creates sample rate
            app.player{2} = audioplayer(app.sample{2},app.sampleRate{2});   % Creates audioplayer object
            plot(app.UIAxes2_2,app.sample{2});                              % Plots waveform on indivdual display
            app.Lamp_2.Color = 'red';                                       % Sets individual status light
            app.DisplayTextArea.Value = '';
            end
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            [filename, filepath] = uigetfile('*.wav');                      % Gets .wav file from user input
            file = strcat(filepath,filename);                               % Creates a full pathname
            [~, ~, extension] = fileparts(file);
            if isempty(file)                                                % Error handling if user cancels file load
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            elseif (strcmpi(extension, '.wav') == false)                    % Error handling if user selects wrong file type
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            else
                [app.permSample{3},app.permRate{3}] = audioread(file);        % Stores audio and rate data to permanent files
                app.sample{3} = app.permSample{3};                              % Creates a sample audio array
                app.sampleRate{3} = app.permRate{3};                            % Creates sample rate
                app.player{3} = audioplayer(app.sample{3},app.sampleRate{3});   % Creates audioplayer object
                plot(app.UIAxes2_3,app.sample{3});                              % Plots waveform on indivdual display
                app.Lamp_3.Color = 'red';                                       % Sets individual status light
                app.DisplayTextArea.Value = '';
            end
        end

        % Button pushed function: LoadButton_4
        function LoadButton_4Pushed(app, event)
            [filename, filepath] = uigetfile('*.wav');                      % Gets .wav file from user input
            file = strcat(filepath,filename);                               % Creates a full pathname
            [~, ~, extension] = fileparts(file);
            if isempty(file)                                                % Error handling if user cancels file load
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            elseif (strcmpi(extension, '.wav') == false)                    % Error handling if user selects wrong file type
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            else
            [app.permSample{4},app.permRate{4}] = audioread(file);        % Stores audio and rate data to permanent files
            app.sample{4} = app.permSample{4};                              % Creates a sample audio array
            app.sampleRate{4} = app.permRate{4};                            % Creates sample rate
            app.player{4} = audioplayer(app.sample{4},app.sampleRate{4});   % Creates audioplayer object
            plot(app.UIAxes2_4,app.sample{4});                              % Plots waveform on indivdual display
            app.Lamp_4.Color = 'red';                                       % Sets individual status light
            app.DisplayTextArea.Value = '';
            end
        end

        % Button pushed function: LoadButton_5
        function LoadButton_5Pushed(app, event)
            [filename, filepath] = uigetfile('*.wav');                      % Gets .wav file from user input
            file = strcat(filepath,filename);                               % Creates a full pathname
            [~, ~, extension] = fileparts(file);
            if isempty(file)                                                % Error handling if user cancels file load
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            elseif (strcmpi(extension, '.wav') == false)                    % Error handling if user selects wrong file type
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            else
                [app.permSample{5},app.permRate{5}] = audioread(file);        % Stores audio and rate data to permanent files
                app.sample{5} = app.permSample{5};                              % Creates a sample audio array
                app.sampleRate{5} = app.permRate{5};                            % Creates sample rate
                app.player{5} = audioplayer(app.sample{5},app.sampleRate{5});   % Creates audioplayer object
                plot(app.UIAxes2_5,app.sample{5});                              % Plots waveform on indivdual display
                app.Lamp_5.Color = 'red';                                       % Sets individual status light
                app.DisplayTextArea.Value = '';
            end
        end

        % Button pushed function: LoadButton_6
        function LoadButton_6Pushed(app, event)
            [filename, filepath] = uigetfile('*.wav');                      % Gets .wav file from user input
            file = strcat(filepath,filename);                               % Creates a full pathname
            [~, ~, extension] = fileparts(file);
            if isempty(file)                                                % Error handling if user cancels file load
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            elseif (strcmpi(extension, '.wav') == false)                    % Error handling if user selects wrong file type
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            else
            [app.permSample{6},app.permRate{6}] = audioread(file);        % Stores audio and rate data to permanent files
            app.sample{6} = app.permSample{6};                              % Creates a sample audio array
            app.sampleRate{6} = app.permRate{6};                            % Creates sample rate
            app.player{6} = audioplayer(app.sample{6},app.sampleRate{6});   % Creates audioplayer object
            plot(app.UIAxes2_6,app.sample{6});                              % Plots waveform on indivdual display
            app.Lamp_6.Color = 'red';                                       % Sets individual status light
            app.DisplayTextArea.Value = '';
            end
        end

        % Button pushed function: LoadButton_7
        function LoadButton_7Pushed(app, event)
            [filename, filepath] = uigetfile('*.wav');                      % Gets .wav file from user input
            file = strcat(filepath,filename);                               % Creates a full pathname
            [~, ~, extension] = fileparts(file);
            if isempty(file)                                                % Error handling if user cancels file load
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            elseif (strcmpi(extension, '.wav') == false)                    % Error handling if user selects wrong file type
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            else
                [app.permSample{7},app.permRate{7}] = audioread(file);        % Stores audio and rate data to permanent files
                app.sample{7} = app.permSample{7};                              % Creates a sample audio array
                app.sampleRate{7} = app.permRate{7};                            % Creates sample rate
                app.player{7} = audioplayer(app.sample{7},app.sampleRate{7});   % Creates audioplayer object
                plot(app.UIAxes2_7,app.sample{7});                              % Plots waveform on indivdual display
                app.Lamp_7.Color = 'red';                                       % Sets individual status light
                app.DisplayTextArea.Value = '';
            end
        end

        % Button pushed function: LoadButton_8
        function LoadButton_8Pushed(app, event)
            [filename, filepath] = uigetfile('*.wav');                      % Gets .wav file from user input
            file = strcat(filepath,filename);                               % Creates a full pathname
            [~, ~, extension] = fileparts(file);
            if isempty(file)                                                % Error handling if user cancels file load
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            elseif (strcmpi(extension, '.wav') == false)                    % Error handling if user selects wrong file type
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            else
            [app.permSample{8},app.permRate{8}] = audioread(file);        % Stores audio and rate data to permanent files
            app.sample{8} = app.permSample{8};                              % Creates a sample audio array
            app.sampleRate{8} = app.permRate{8};                            % Creates sample rate
            app.player{8} = audioplayer(app.sample{8},app.sampleRate{8});   % Creates audioplayer object
            plot(app.UIAxes2_8,app.sample{8});                              % Plots waveform on indivdual display
            app.Lamp_8.Color = 'red';                                       % Sets individual status light
             app.DisplayTextArea.Value = '';
            end
        end

        % Button pushed function: LoadButton_9
        function LoadButton_9Pushed(app, event)
            [filename, filepath] = uigetfile('*.wav');                      % Gets .wav file from user input
            file = strcat(filepath,filename);                               % Creates a full pathname
            [~, ~, extension] = fileparts(file);
            if isempty(file)                                                % Error handling if user cancels file load
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            elseif (strcmpi(extension, '.wav') == false)                    % Error handling if user selects wrong file type
                msgbox("Please load a .wav file");
                app.DisplayTextArea.Value = "Please load a .wav file";
            else
                [app.permSample{9},app.permRate{9}] = audioread(file);        % Stores audio and rate data to permanent files
                app.sample{9} = app.permSample{9};                              % Creates a sample audio array
                app.sampleRate{9} = app.permRate{9};                            % Creates sample rate
                app.player{9} = audioplayer(app.sample{9},app.sampleRate{9});   % Creates audioplayer object
                plot(app.UIAxes2_9,app.sample{9});                              % Plots waveform on indivdual display
                app.Lamp_9.Color = 'red';                                       % Sets individual status light
                app.DisplayTextArea.Value = '';
            end
        end

        % Button pushed function: PlayButton_1
        function PlayButton_1Pushed(app, event)
            if (app.permSample{1}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            app.Lamp_1.Color = 'green';       % changes lamp colour to green

            if (app.pauseFlag(1) == true)     % Checks to see if sample is paused
                resume(app.player{1});        % If paused, resumes play from current point
                app.pauseFlag(1) = false;     % Resets pause flag
            else
                play(app.player{1});          % If not paused, plays from beginning
            end
            pause((app.player{1}.TotalSamples - app.player{1}.CurrentSample)/app.sampleRate{1});
            app.Lamp_1.Color = 'red';         % Turns off play lamp after sample plays
            end
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            if (app.permSample{2}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.Lamp_2.Color = 'green';       % changes lamp colour to green
                
                if (app.pauseFlag(2) == true)     % Checks to see if sample is paused
                    resume(app.player{2});        % If paused, resumes play from current point
                    app.pauseFlag(2) = false;     % Resets pause flag
                else
                    play(app.player{2});          % If not paused, plays from beginning
                end
                pause((app.player{2}.TotalSamples - app.player{2}.CurrentSample)/app.sampleRate{2});
                app.Lamp_2.Color = 'red';         % Turns off play lamp after sample plays
            end
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
            if (app.permSample{3}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            app.Lamp_3.Color = 'green';       % changes lamp colour to green

            if (app.pauseFlag(3) == true)     % Checks to see if sample is paused
                resume(app.player{3});        % If paused, resumes play from current point
                app.pauseFlag(3) = false;     % Resets pause flag
            else
                play(app.player{3});          % If not paused, plays from beginning
            end
            pause((app.player{3}.TotalSamples - app.player{3}.CurrentSample)/app.sampleRate{3});
            app.Lamp_3.Color = 'red';         % Turns off play lamp after sample plays
            end
        end

        % Button pushed function: PlayButton_4
        function PlayButton_4Pushed(app, event)
            if (app.permSample{4}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.Lamp_4.Color = 'green';       % changes lamp colour to green
                
                if (app.pauseFlag(4) == true)     % Checks to see if sample is paused
                    resume(app.player{4});        % If paused, resumes play from current point
                    app.pauseFlag(4) = false;     % Resets pause flag
                else
                    play(app.player{4});          % If not paused, plays from beginning
                end
                pause((app.player{4}.TotalSamples - app.player{4}.CurrentSample)/app.sampleRate{4});
                app.Lamp_4.Color = 'red';         % Turns off play lamp after sample plays
            end
        end

        % Button pushed function: PlayButton_5
        function PlayButton_5Pushed(app, event)
            if (app.permSample{5}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            app.Lamp_5.Color = 'green';       % changes lamp colour to green

            if (app.pauseFlag(5) == true)     % Checks to see if sample is paused
                resume(app.player{5});        % If paused, resumes play from current point
                app.pauseFlag(5) = false;     % Resets pause flag
            else
                play(app.player{5});          % If not paused, plays from beginning
            end
            pause((app.player{5}.TotalSamples - app.player{5}.CurrentSample)/app.sampleRate{5});
            app.Lamp_5.Color = 'red';         % Turns off play lamp after sample plays
            end
        end

        % Button pushed function: PlayButton_6
        function PlayButton_6Pushed(app, event)
            if (app.permSample{6}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.Lamp_6.Color = 'green';       % changes lamp colour to green
                
                if (app.pauseFlag(6) == true)     % Checks to see if sample is paused
                    resume(app.player{6});        % If paused, resumes play from current point
                    app.pauseFlag(6) = false;     % Resets pause flag
                else
                    play(app.player{6});          % If not paused, plays from beginning
                end
                pause((app.player{6}.TotalSamples - app.player{6}.CurrentSample)/app.sampleRate{6});
                app.Lamp_6.Color = 'red';         % Turns off play lamp after sample plays
            end
        end

        % Button pushed function: PlayButton_7
        function PlayButton_7Pushed(app, event)
            if (app.permSample{7}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            app.Lamp_7.Color = 'green';       % changes lamp colour to green

            if (app.pauseFlag(7) == true)     % Checks to see if sample is paused
                resume(app.player{7});        % If paused, resumes play from current point
                app.pauseFlag(7) = false;     % Resets pause flag
            else
                play(app.player{7});          % If not paused, plays from beginning
            end
            pause((app.player{7}.TotalSamples - app.player{7}.CurrentSample)/app.sampleRate{7});
            app.Lamp_7.Color = 'red';         % Turns off play lamp after sample plays
            end
        end

        % Button pushed function: PlayButton_8
        function PlayButton_8Pushed(app, event)
            if (app.permSample{8}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.Lamp_8.Color = 'green';       % changes lamp colour to green
                
                if (app.pauseFlag(8) == true)     % Checks to see if sample is paused
                    resume(app.player{8});        % If paused, resumes play from current point
                    app.pauseFlag(8) = false;     % Resets pause flag
                else
                    play(app.player{8});          % If not paused, plays from beginning
                end
                pause((app.player{8}.TotalSamples - app.player{8}.CurrentSample)/app.sampleRate{8});
                app.Lamp_8.Color = 'red';         % Turns off play lamp after sample plays
            end
        end

        % Button pushed function: PlayButton_9
        function PlayButton_9Pushed(app, event)
            if (app.permSample{9}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            app.Lamp_9.Color = 'green';       % changes lamp colour to green

            if (app.pauseFlag(9) == true)     % Checks to see if sample is paused
                resume(app.player{9});        % If paused, resumes play from current point
                app.pauseFlag(9) = false;     % Resets pause flag
            else
                play(app.player{9});          % If not paused, plays from beginning
            end
            pause((app.player{9}.TotalSamples - app.player{9}.CurrentSample)/app.sampleRate{9});
            app.Lamp_9.Color = 'red';         % Turns off play lamp after sample plays
            end
        end

        % Button pushed function: PauseButton_1
        function PauseButton_1Pushed(app, event)
            if (app.permSample{1}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                pause(app.player{1});
                app.pauseFlag(1) = true;             % Sets pause flag
                app.Lamp_1.Color = 'red';            % changes lamp colour to red
            end
        end

        % Button pushed function: PauseButton_2
        function PauseButton_2Pushed(app, event)
            if (app.permSample{2}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            pause(app.player{2});
            app.pauseFlag(2) = true;             % Sets pause flag
            app.Lamp_2.Color = 'red';            % changes lamp colour to red
            end
        end

        % Button pushed function: PauseButton_3
        function PauseButton_3Pushed(app, event)
            if (app.permSample{3}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            pause(app.player{3});
            app.pauseFlag(3) = true;             % Sets pause flag
            app.Lamp_3.Color = 'red';            % changes lamp colour to red
            end
        end

        % Button pushed function: PauseButton_4
        function PauseButton_4Pushed(app, event)
            if (app.permSample{4}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                pause(app.player{4});
                app.pauseFlag(4) = true;             % Sets pause flag
                app.Lamp_4.Color = 'red';            % changes lamp colour to red
            end
        end

        % Button pushed function: PauseButton_5
        function PauseButton_5Pushed(app, event)
            if (app.permSample{5}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            pause(app.player{5});
            app.pauseFlag(5) = true;             % Sets pause flag
            app.Lamp_5.Color = 'red';            % changes lamp colour to red
            end
        end

        % Button pushed function: PauseButton_6
        function PauseButton_6Pushed(app, event)
            if (app.permSample{6}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                pause(app.player{6});
                app.pauseFlag(6) = true;             % Sets pause flag
                app.Lamp_6.Color = 'red';            % changes lamp colour to red
            end
        end

        % Button pushed function: PauseButton_7
        function PauseButton_7Pushed(app, event)
            if (app.permSample{7}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            pause(app.player{7});
            app.pauseFlag(7) = true;             % Sets pause flag
            app.Lamp_7.Color = 'red';            % changes lamp colour to red
            end
        end

        % Button pushed function: PauseButton_8
        function PauseButton_8Pushed(app, event)
            if (app.permSample{8}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                pause(app.player{8});
                app.pauseFlag(8) = true;             % Sets pause flag
                app.Lamp_8.Color = 'red';            % changes lamp colour to red
            end
        end

        % Button pushed function: PauseButton_9
        function PauseButton_9Pushed(app, event)
            if (app.permSample{9}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            pause(app.player{9});
            app.pauseFlag(9) = true;             % Sets pause flag
            app.Lamp_9.Color = 'red';            % changes lamp colour to red
            end
        end

        % Value changed function: VolumeKnob_1
        function VolumeKnob_1ValueChanged(app, event)
            
            % Sets volume level using input from knob in an exponential function
            % to scale the permanent audio file and store as the sample audio file
            
            if (app.permSample{1}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.sample{1} =((1.21* exp((app.VolumeKnob_1.Value)*.0120412))-1.21)* app.permSample{1};
                location = app.player{1}.CurrentSample;                         % Stores location of current sample
                playStatus = isplaying(app.player{1});                          % Stores playing status of original sample
                app.player{1} = audioplayer(app.sample{1},app.sampleRate{1});   % Creates new audioplayer object from scaled audio sample
                if ( playStatus == true)                                        % Plays from original location at new volume if originally playing
                    play(app.player{1}, location);
                    pause((app.player{1}.TotalSamples - location)/app.sampleRate{1});
                    app.Lamp_1.Color = 'red';                                 % Turns off play lamp after sample plays
                end
            end
        end

        % Value changed function: VolumeKnob_2
        function VolumeKnob_2ValueChanged(app, event)
            
            % Sets volume level using input from knob in an exponential function
            % to scale the permanent audio file and store as the sample audio file

            if (app.permSample{2}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            app.sample{2} =((1.21* exp((app.VolumeKnob_2.Value)*.0120412))-1.21)* app.permSample{2};
            location = app.player{2}.CurrentSample;                         % Stores location of current sample
            playStatus = isplaying(app.player{2});                          % Stores playing status of original sample
            app.player{2} = audioplayer(app.sample{2},app.sampleRate{2});   % Creates new audioplayer object from scaled audio sample
            if ( playStatus == true)                                        % Plays from original location at new volume if originally playing
                play(app.player{2}, location);
                pause((app.player{2}.TotalSamples - location)/app.sampleRate{2});
                app.Lamp_2.Color = 'red';                                 % Turns off play lamp after sample plays
            end
            end
        end

        % Value changed function: VolumeKnob_3
        function VolumeKnob_3ValueChanged(app, event)
            
            % Sets volume level using input from knob in an exponential function
            % to scale the permanent audio file and store as the sample audio file
            
            if (app.permSample{3}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.sample{3} =((1.21* exp((app.VolumeKnob_3.Value)*.0120412))-1.21)* app.permSample{3};
                location = app.player{3}.CurrentSample;                         % Stores location of current sample
                playStatus = isplaying(app.player{3});                          % Stores playing status of original sample
                app.player{3} = audioplayer(app.sample{3},app.sampleRate{3});   % Creates new audioplayer object from scaled audio sample
                if ( playStatus == true)                                        % Plays from original location at new volume if originally playing
                    play(app.player{3}, location);
                    pause((app.player{3}.TotalSamples - location)/app.sampleRate{3});
                    app.Lamp_3.Color = 'red';                                 % Turns off play lamp after sample plays
                end
            end
        end

        % Value changed function: VolumeKnob_4
        function VolumeKnob_4ValueChanged(app, event)
            
            % Sets volume level using input from knob in an exponential function
            % to scale the permanent audio file and store as the sample audio file
            
            if (app.permSample{4}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.sample{4} =((1.21* exp((app.VolumeKnob_4.Value)*.0120412))-1.21)* app.permSample{4};
                location = app.player{4}.CurrentSample;                         % Stores location of current sample
                playStatus = isplaying(app.player{4});                          % Stores playing status of original sample
                app.player{4} = audioplayer(app.sample{4},app.sampleRate{4});   % Creates new audioplayer object from scaled audio sample
                if ( playStatus == true)                                        % Plays from original location at new volume if originally playing
                    play(app.player{4}, location);
                    pause((app.player{4}.TotalSamples - location)/app.sampleRate{4});
                    app.Lamp_4.Color = 'red';                                 % Turns off play lamp after sample plays
                end
            end
        end

        % Value changed function: VolumeKnob_5
        function VolumeKnob_5ValueChanged(app, event)
            
            % Sets volume level using input from knob in an exponential function
            % to scale the permanent audio file and store as the sample audio file

            if (app.permSample{5}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            app.sample{5} =((1.21* exp((app.VolumeKnob_5.Value)*.0120412))-1.21)* app.permSample{5};
            location = app.player{5}.CurrentSample;                         % Stores location of current sample
            playStatus = isplaying(app.player{5});                          % Stores playing status of original sample
            app.player{5} = audioplayer(app.sample{5},app.sampleRate{5});   % Creates new audioplayer object from scaled audio sample
            if ( playStatus == true)                                        % Plays from original location at new volume if originally playing
                play(app.player{5}, location);
                pause((app.player{5}.TotalSamples - location)/app.sampleRate{5});
                app.Lamp_5.Color = 'red';                                 % Turns off play lamp after sample plays
            end
            end
        end

        % Value changed function: VolumeKnob_6
        function VolumeKnob_6ValueChanged(app, event)
            
            % Sets volume level using input from knob in an exponential function
            % to scale the permanent audio file and store as the sample audio file
            
            if (app.permSample{6}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.sample{6} =((1.21* exp((app.VolumeKnob_6.Value)*.0120412))-1.21)* app.permSample{6};
                location = app.player{6}.CurrentSample;                         % Stores location of current sample
                playStatus = isplaying(app.player{6});                          % Stores playing status of original sample
                app.player{6} = audioplayer(app.sample{6},app.sampleRate{6});   % Creates new audioplayer object from scaled audio sample
                if ( playStatus == true)                                        % Plays from original location at new volume if originally playing
                    play(app.player{6}, location);
                    pause((app.player{6}.TotalSamples - location)/app.sampleRate{6});
                    app.Lamp_6.Color = 'red';                                 % Turns off play lamp after sample plays
                end
            end
        end

        % Value changed function: VolumeKnob_7
        function VolumeKnob_7ValueChanged(app, event)
            
            % Sets volume level using input from knob in an exponential function
            % to scale the permanent audio file and store as the sample audio file
            
            if (app.permSample{7}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.sample{7} =((1.21* exp((app.VolumeKnob_7.Value)*.0120412))-1.21)* app.permSample{7};
                location = app.player{7}.CurrentSample;                         % Stores location of current sample
                playStatus = isplaying(app.player{7});                          % Stores playing status of original sample
                app.player{7} = audioplayer(app.sample{7},app.sampleRate{7});   % Creates new audioplayer object from scaled audio sample
                if ( playStatus == true)                                        % Plays from original location at new volume if originally playing
                    play(app.player{7}, location);
                    pause((app.player{7}.TotalSamples - location)/app.sampleRate{7});
                    app.Lamp_7.Color = 'red';                                 % Turns off play lamp after sample plays
                end
            end
        end

        % Value changed function: VolumeKnob_8
        function VolumeKnob_8ValueChanged(app, event)
            
            % Sets volume level using input from knob in an exponential function
            % to scale the permanent audio file and store as the sample audio file
            
            if (app.permSample{8}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.sample{8} =((1.21* exp((app.VolumeKnob_8.Value)*.0120412))-1.21)* app.permSample{8};
                location = app.player{8}.CurrentSample;                         % Stores location of current sample
                playStatus = isplaying(app.player{8});                          % Stores playing status of original sample
                app.player{8} = audioplayer(app.sample{8},app.sampleRate{8});   % Creates new audioplayer object from scaled audio sample
                if ( playStatus == true)                                        % Plays from original location at new volume if originally playing
                    play(app.player{8}, location);
                    pause((app.player{8}.TotalSamples - location)/app.sampleRate{8});
                    app.Lamp_8.Color = 'red';                                 % Turns off play lamp after sample plays
                end
            end
        end

        % Value changed function: VolumeKnob_9
        function VolumeKnob_9ValueChanged(app, event)
            
            % Sets volume level using input from knob in an exponential function
            % to scale the permanent audio file and store as the sample audio file
            
            if (app.permSample{9}==0)                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No file loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                app.sample{9} =((1.21* exp((app.VolumeKnob_9.Value)*.0120412))-1.21)* app.permSample{9};
                location = app.player{9}.CurrentSample;                         % Stores location of current sample
                playStatus = isplaying(app.player{9});                          % Stores playing status of original sample
                app.player{9} = audioplayer(app.sample{9},app.sampleRate{9});   % Creates new audioplayer object from scaled audio sample
                if ( playStatus == true)                                        % Plays from original location at new volume if originally playing
                    play(app.player{9}, location);
                    pause((app.player{9}.TotalSamples - location)/app.sampleRate{9});
                    app.Lamp_9.Color = 'red';                                 % Turns off play lamp after sample plays
                end
            end
        end

        % Button pushed function: PauseButton_System
        function PauseButton_SystemPushed(app, event)
            if (isequal(app.permSample{10},0))                                       % Error handling if no file loaded
                app.DisplayTextArea.Value = 'No sample loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                
                pause(app.player{10});
                app.pauseFlag(10) = true;             % Sets pause flag
                app.Lamp_System.Color = 'red';        % changes lamp colour to red
            end
        end

        % Value changed function: LoadSampleDropDown
        function LoadSampleDropDownValueChanged(app, event)
            if strcmp(app.LoadSampleDropDown.Value,'Select Sample')                 % Error handling if sample is not selected
                app.DisplayTextArea.Value = ('Please select sample number');
                pause(5)
                app.DisplayTextArea.Value = '';
            else
                value = int8(str2double(app.LoadSampleDropDown.Value));
                if (isequal(app.sample{value},0))||(isempty(app.sample{value}))
                    app.DisplayTextArea.Value = ('Selected sample is empty');
                    pause(5)
                    app.DisplayTextArea.Value = '';
                else
                    
                    
                    app.Lamp_System.Color = 'red';                                          % Turns on play lamp


                    app.sample{10} = app.permSample{value};                                 % Assigns system properties from selected sample properties
                    app.permSample{10} = app.permSample{value};
                    app.sampleRate{10} = app.sampleRate{value};
                    app.permRate{10} = app.permRate{value};
                    app.player{10} = audioplayer(app.sample{10},app.sampleRate{10});
                    
                    for i = 1:10                                                            % Initializes equalizer samples
                        app.equalizerSample{i} = app.permSample{value};
                    end
                    
                    N = size(app.sample{10},1);                                             % Length in time domain
                    n=nextpow2(N);
                    N_fft=2^n;                                                              % Length in frequency domain
                    
                    app.freqSample=fftshift(fft(app.permSample{10},N_fft));                 % Sample after fast Fourier transform
                    app.freq=(-N_fft/2:N_fft/2-1)*app.sampleRate{10}/N;                     % Frequency vector
                    
                    plot(app.UIAxes_System,app.sample{10});                                 % Plots the waveform of the audio
                end
            end
        end

        % Value changed function: EqSlider_32
        function EqSlider_32ValueChanged(app, event)
            f_selected = ((app.freq <= 48 & app.freq >= -48));                % Frequency band to be filtered
            Y_mod = app.freqSample;
            [m,n] = size(Y_mod); %#ok<ASGLU>
            if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                Y_mod1 = Y_mod(:,1);
                Y_mod2 = Y_mod(:,2);
                Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_32.Value;
                Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_32.Value;
                app.equalizerSample{1} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
            end
            
            if (n==1)                                                         % For mono, applies slider value to selected frequency band
                Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_32.Value;
                app.equalizerSample{1} = ifft(ifftshift(Y_mod));
            end
            
            
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Button pushed function: PlayButton_System
        function PlayButton_SystemPushed(app, event)
            
            % Sample 12 comes from mixer, system should not display an error
            % if the load drop down is not selected if the playing sample
            % originates from the mixer panel
       
            if ((strcmpi(app.LoadSampleDropDown.Value, 'Select Sample') && ...        % Displays an error if load drop down is not set and file is not originated in mixer
                    ((isequal(size(app.permSample{10}),size(app.sample{12}))==false) || ...
                    ((isequal(size(app.permSample{10}),size(app.sample{12}))) && ((isequal(app.permSample{10},app.sample{12}))==false)))))
                
                app.DisplayTextArea.Value = "Load a sample";
            elseif (isequal(app.permSample{10},0)||(isempty(app.permSample{10})))
                app.DisplayTextArea.Value = "Load a sample";
            else
               
                
                app.Lamp_System.Color = 'green';                                             % Turns on system lamp
                
                [m,n] = size(app.sample{10}); %#ok<ASGLU>                                   % Checks for number of channels in current sample and its permanent version
                [p,q] = size(app.permSample{10}); %#ok<ASGLU>
                
                if (n==1 && q==2 && strcmp(app.Switch_Stereo.Value, 'Stereo'))              % Changes a sample back to stereo after it had been changed to mono
                    app.sample{10} = app.permSample{10};
                    n=2;
                end
                
                location = 1;                                                               % Sets sample location to beginning of sample or current sample if playing or paused
                if (isplaying(app.player{10}) == true || app.pauseFlag(10) == true)
                    location = app.player{10}.CurrentSample;
                end
                
                if (app.pauseFlag(11) == true)
                    location = app.trackLocation;
                end
                
                if ((isplaying(app.player{10}) == false) || app.pauseFlag(10) == true)
                    
                    app.EqSlider_32ValueChanged();                                              % Gather all initial settings
                    app.EqSlider_64ValueChanged();                                              % Isolated in if statement to avoid infinite loop
                    app.EqSlider_125ValueChanged();
                    app.EqSlider_250ValueChanged();
                    app.EqSlider_500ValueChanged();
                    app.EqSlider_1kValueChanged();
                    app.EqSlider_2kValueChanged();
                    app.EqSlider_4kValueChanged();
                    app.EqSlider_8kValueChanged();
                    app.EqSlider_16kValueChanged();
                    app.VolumeKnob_SystemValueChanged();
                    app.Switch_Direction();
                    app.ClipSlider1ValueChanged();
                    app.ClipSlider2ValueChanged();
                    app.Switch_StereoValueChanged();
                    
                end
                
                
                clipStart = min([app.ClipSlider1.Value, app.ClipSlider2.Value]);            % Reads position of clipping function sliders and sets start and stop
                clipStop = max([app.ClipSlider1.Value, app.ClipSlider2.Value]);
                
                start = ceil(clipStart/100 * size(app.permSample{10}) + .1);                % Scales slider value to sample location.  Rounds to avoid out of bounds error
                stop = floor(clipStop/100 * size(app.permSample{10}) - .1);
                if (location > stop)                                                        % Sets location if user cuts sample shorter than current location
                    location = 1;
                end
                
                temp = app.equalizerSample{1};                              % Loop to gather frequency band audio from equalizer
                for i = 2:10                                                % Important note: Equalizer takes permsample to avoid positive feedback
                    temp = app.equalizerSample{i} + temp;
                end
                app.sample{11} = .1*temp;                                   % Averages the equalizer chanels into a single output
                
                if (n == 1)                                                 % For a mono sample, assigns the sample to the system audio sample
                    
                    app.sample{10} = app.sample{11}(1:size(app.permSample{10}));
                    app.sample{10} = app.sample{10}(start:stop);
                    
                end
                
                if (n == 2)                                                 % For a stereo sample, assigns the sample to the system audio, accounting for each channel
                    left = app.sample{11}(start:stop,1);
                    right = app.sample{11}(start:stop,2);
                    app.sample{10} = [left,right];
                end
                
                
                if (n==2 && strcmp(app.Switch_Stereo.Value, 'Mono'))             % If system is stereo and switch is set to mono
                    temp2 = sum(app.sample{10},2);                               % Creates single combined channel
                    tempPeakAmp = max(abs(temp2));                               % Finds maximum of combined channel
                    temp2 = temp2./tempPeakAmp;                                    % Normalizes combined channel to combined max
                    peak1 = max(abs(app.sample{10}(:,1)));                      % Find maximum of original channels
                    peak2 = max(abs(app.sample{10}(:,2)));
                    peakAmp = max([peak1, peak2]);                              % Find maximum of original sample
                    app.sample{10} = temp2 * peakAmp;                            % Scale combined sample to original maximum
                    n=1;
                end


                if (strcmp(app.Switch_Direction.Value,'Reverse'))               % Reverses system direction
                    app.sample{10} = flipud(app.sample{10});
                end
                
                app.sample{10} = ((1.21* exp((app.VolumeKnob_System.Value)*.0120412))-1.21) .* app.sample{10};  % Adjusts volume


                echoLevel = ceil(app.EchoSlider_2.Value)/5;                        % Gets value of echo slider
                delayLevel = ceil(app.DelaySlider.Value) ;                          % Gets value of delay slider
                Ts = 1/app.sampleRate{10};                                          % Time interval betwee samples
                shift = ceil(delayLevel/Ts);                                        % Amount to shift the audio vector
                
                if (n==1)                                                           % For mono, implements delay
                    y = app.sample{10}(:,1);
                    y_delay = zeros(length(y)+(delayLevel-1)*shift,1);
                    for k = 0:delayLevel-1
                        y1 = [zeros(k*shift,1);y;zeros((delayLevel-1-k)*shift,1)];  % Creates repeated delayed sample
                        y_delay = y_delay+1/2^(k*echoLevel)*y1;                     % Decreases volume of sample with each iteration according to echo level
                        app.sample{10} = y_delay;
                        
                    end
                else
                    yleft = app.sample{10}(:,1);                                    % For stereo case, adjusts each channel individually
                    yright = app.sample{10}(:,2);
                    y_delayleft = zeros(length(yleft)+(delayLevel-1)*shift,1);
                    y_delayright = zeros(length(yright)+(delayLevel-1)*shift,1);
                    
                    for k = 0:delayLevel-1
                        y1left = [zeros(k*shift,1);yleft;zeros((delayLevel-1-k)*shift,1)];
                        y_delayleft = y_delayleft+1/2^(k*echoLevel)*y1left;
                    end
                    
                    for l = 0:delayLevel-1
                        y1right = [zeros(l*shift,1);yright;zeros((delayLevel-1-l)*shift,1)];
                        y_delayright = y_delayright+1/2^(l*echoLevel)*y1right;
                    end
                    app.sample{10} = [y_delayleft, y_delayright];                   % Combines right and left vectors into single audio array
                end


                plot(app.UIAxes_System,app.sample{10});      % Plots the waveform of the audio
                
                if (app.pauseFlag(10) == true)               % Checks to see if sample is paused
                    app.pauseFlag(10) = false;               % Resets pause flag
                end
                app.sampleRate{10} = app.permRate{10} * (app.SpeedSlider.Value);       % Sets speed of sample according to speed slider
                if (app.sampleRate{10} < 1000)                                                  % Checks for minimum sample rate
                    app.sampleRate{10} = 1000;
                end
                app.player{10} = audioplayer(app.sample{10}, app.sampleRate{10});
                play(app.player{10}, location);                    % Plays the audio sample
                
                pause((app.player{10}.TotalSamples - app.player{10}.CurrentSample)/app.sampleRate{10});
                app.Lamp_System.Color = 'red';         % Turns off play lamp after sample plays
                
            end
        end

        % Button pushed function: PlayButton_Mixer
        function PlayButton_MixerPushed(app, event)
            if (app.SampleRateEditField.Value == 0)                                 % Error handling for sample rate and length errors
                app.DisplayTextArea.Value = 'Please enter positive sample rate';
                pause(3)
                app.DisplayTextArea.Value = '';
            elseif (app.SampleRateEditField.Value < 0)
                app.DisplayTextArea.Value = 'Please enter positive sample rate';
                pause(3)
                app.DisplayTextArea.Value = '';
            elseif (app.SampleLengthminsEditField.Value == 0)
                app.DisplayTextArea.Value = 'Please enter track length';
                pause(3)
                app.DisplayTextArea.Value = '';
            elseif (app.SampleLengthminsEditField.Value <= 0)
                app.DisplayTextArea.Value = 'Please enter positive track length';
                pause(3)
                app.DisplayTextArea.Value = '';
            elseif (app.SampleRateEditField.Value<=80)
                app.DisplayTextArea.Value = 'Sample rate must be larger than 80';
                pause(3)
                app.DisplayTextArea.Value = '';
            else


                rate = app.SampleRateEditField.Value;
                trackLength = app.SampleLengthminsEditField.Value * 60 * rate;
                track = zeros(trackLength,2);                                       % Builds an empty 2 channel audio sample the desired length
                
                
                location(1) = (app.Track1Slider.Value/100) * trackLength;           % Converts slider positions to sample positions
                location(2) = (app.Track2Slider.Value/100) * trackLength;
                location(3) = (app.Track3Slider.Value/100) * trackLength;
                location(4) = (app.Track4Slider.Value/100) * trackLength;
                location(5) = (app.Track5Slider.Value/100) * trackLength;
                location(6) = (app.Track6Slider.Value/100) * trackLength;
                location(7) = (app.Track7Slider.Value/100) * trackLength;
                location(8) = (app.Track8Slider.Value/100) * trackLength;
                
                loadedTrack = 14*ones(1,8);                                         % Vector to store number of sample to be loaded.  Default to 14, where sample{14} is initialized to 0
                
                if (strcmp(app.Track1DropDown.Value,'Select Sample')==false)
                    loadedTrack(1) = int8(str2double(app.Track1DropDown.Value));
                end
                if (strcmp(app.Track2DropDown.Value,'Select Sample')==false)
                    loadedTrack(2) = int8(str2double(app.Track2DropDown.Value));
                end
                if (strcmp(app.Track3DropDown.Value,'Select Sample')==false)
                    loadedTrack(3) = int8(str2double(app.Track3DropDown.Value));
                end
                if (strcmp(app.Track4DropDown.Value,'Select Sample')==false)
                    loadedTrack(4) = int8(str2double(app.Track4DropDown.Value));
                end
                if (strcmp(app.Track5DropDown.Value,'Select Sample')==false)
                    loadedTrack(5) = int8(str2double(app.Track5DropDown.Value));
                end
                if (strcmp(app.Track6DropDown.Value,'Select Sample')==false)
                    loadedTrack(6) = int8(str2double(app.Track6DropDown.Value));
                end
                if (strcmp(app.Track7DropDown.Value,'Select Sample')==false)
                    loadedTrack(7) = int8(str2double(app.Track7DropDown.Value));
                end
                if (strcmp(app.Track8DropDown.Value,'Select Sample')==false)
                    loadedTrack(8) = int8(str2double(app.Track8DropDown.Value));
                end
                
                sampleTrack = cell(8);                                            % Creates and fills a cell array of the track data to be inserted
                for i=1:8
                    sampleTrack{i} = app.sample{loadedTrack(i)};
                end
                tempTrack = track;                                                % Temp track is passed to AddTrack function to insert each individual sample into a blank track and then
                % all samples are summed to create one track
                
                track = app.AddTrack(tempTrack,sampleTrack{1},location(1)) + app.AddTrack(tempTrack,sampleTrack{2}, location(2)) + app.AddTrack(tempTrack,sampleTrack{3}, location(3))...
                    + app.AddTrack(tempTrack,sampleTrack{4}, location(4)) + app.AddTrack(tempTrack,sampleTrack{5}, location(5)) + app.AddTrack(tempTrack,sampleTrack{6}, location(6))...
                    + app.AddTrack(tempTrack,sampleTrack{7}, location(7)) + app.AddTrack(tempTrack,sampleTrack{8}, location(8));
                
                
                left = track(:,1);                                                  % Trims leading and trailing zeros from audio sample
                first = find(left,1,'first');
                last = find(left,1,'last');
                trackleft = track(first:last,1);
                trackright = track(first:last,2);
                track = [trackleft, trackright];
                
                
                app.sample{10} = track;                                             % Assigns player data to system player location
                app.sample{12} = track;                                             % Sample 12 is a permanent sample for comparison purposes
                app.permSample{10} = track;
                app.sampleRate{10} = rate;
                app.permRate{10} = rate;
                plot(app.UIAxes,track);
                
                for i = 1:10                                                            % Initializes equalizer samples
                    app.equalizerSample{i} = app.permSample{10};
                end
                
                N = size(app.sample{10},1);                                             % Length in time domain
                n=nextpow2(N);
                N_fft=2^n;                                                              % Length in frequency domain
                
                app.freqSample=fftshift(fft(app.permSample{10},N_fft));                 % Sample after fast Fourier transform
                app.freq=(-N_fft/2:N_fft/2-1)*app.sampleRate{10}/N;                     % Frequency vector
                
                if (isempty(track))                                                     % Error handling if track is empty
                    app.DisplayTextArea.Value = "No samples placed";
                    pause(3)
                    app.DisplayTextArea.Value = '';
                else
                app.Lamp_Mixer.Color = 'green';                                     % changes lamp colour to green
                app.PlayButton_SystemPushed();          % Calls to system play button to play the sample
                pause((app.player{10}.TotalSamples - app.player{10}.CurrentSample)/app.sampleRate{10});
                app.Lamp_Mixer.Color = 'red';         % Turns off play lamp after sample plays
                end
            end
        end

        % Value changed function: EqSlider_64
        function EqSlider_64ValueChanged(app, event)
            f_selected = ((app.freq <= 94 & app.freq > 48) | (app.freq >= -94 & app.freq < -48));  % Frequency band to be filtered
            Y_mod = app.freqSample;
            [m,n] = size(Y_mod); %#ok<ASGLU>
            if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                Y_mod1 = Y_mod(:,1);
                Y_mod2 = Y_mod(:,2);
                Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_64.Value;
                Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_64.Value;
                app.equalizerSample{2} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
            end
            
            if (n==1)                                                         % For mono, applies slider value to selected frequency band
                Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_64.Value;
                app.equalizerSample{2} = ifft(ifftshift(Y_mod));
            end
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: EqSlider_125
        function EqSlider_125ValueChanged(app, event)

                    f_selected = ((app.freq <= 188 & app.freq > 94) | (app.freq >= -188 & app.freq < -94));  % Frequency band to be filtered
                    Y_mod = app.freqSample;
                    [m,n] = size(Y_mod); %#ok<ASGLU> 
                    if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                       Y_mod1 = Y_mod(:,1);
                       Y_mod2 = Y_mod(:,2);                 
                        Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_125.Value;
                        Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_125.Value;
                        app.equalizerSample{3} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
                    end
                    
                    if (n==1)                                                         % For mono, applies slider value to selected frequency band
                        Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_125.Value;
                        app.equalizerSample{3} = ifft(ifftshift(Y_mod));
                    end
                    if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                        app.PlayButton_SystemPushed()
                    end        
        end

        % Value changed function: EqSlider_250
        function EqSlider_250ValueChanged(app, event)
            f_selected = ((app.freq <= 375 & app.freq > 188) | (app.freq >= -375 & app.freq < -188));  % Frequency band to be filtered
            Y_mod = app.freqSample;
            [m,n] = size(Y_mod); %#ok<ASGLU>
            if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                Y_mod1 = Y_mod(:,1);
                Y_mod2 = Y_mod(:,2);
                Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_250.Value;
                Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_250.Value;
                app.equalizerSample{4} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
            end
            
            if (n==1)                                                         % For mono, applies slider value to selected frequency band
                Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_250.Value;
                app.equalizerSample{4} = ifft(ifftshift(Y_mod));
            end
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: EqSlider_500
        function EqSlider_500ValueChanged(app, event)
            
            f_selected = ((app.freq <= 750 & app.freq > 375) | (app.freq >= -750 & app.freq < -375));  % Frequency band to be filtered
            Y_mod = app.freqSample;
            [m,n] = size(Y_mod); %#ok<ASGLU>
            if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                Y_mod1 = Y_mod(:,1);
                Y_mod2 = Y_mod(:,2);
                Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_500.Value;
                Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_500.Value;
                app.equalizerSample{5} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
            end
            
            if (n==1)                                                         % For mono, applies slider value to selected frequency band
                Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_500.Value;
                app.equalizerSample{5} = ifft(ifftshift(Y_mod));
            end
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: EqSlider_1k
        function EqSlider_1kValueChanged(app, event)
            f_selected = ((app.freq <= 1500 & app.freq > 750) | (app.freq >= -1500 & app.freq < -750));  % Frequency band to be filtered
            Y_mod = app.freqSample;
            [m,n] = size(Y_mod); %#ok<ASGLU>
            if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                Y_mod1 = Y_mod(:,1);
                Y_mod2 = Y_mod(:,2);
                Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_1k.Value;
                Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_1k.Value;
                app.equalizerSample{6} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
            end
            
            if (n==1)                                                         % For mono, applies slider value to selected frequency band
                Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_1k.Value;
                app.equalizerSample{6} = ifft(ifftshift(Y_mod));
            end
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: EqSlider_2k
        function EqSlider_2kValueChanged(app, event)
            f_selected = ((app.freq <= 3000 & app.freq > 1500) | (app.freq >= -3000 & app.freq < -1500));  % Frequency band to be filtered
            Y_mod = app.freqSample;
            [m,n] = size(Y_mod); %#ok<ASGLU>
            if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                Y_mod1 = Y_mod(:,1);
                Y_mod2 = Y_mod(:,2);
                Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_2k.Value;
                Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_2k.Value;
                app.equalizerSample{7} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
            end
            
            if (n==1)                                                         % For mono, applies slider value to selected frequency band
                Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_2k.Value;
                app.equalizerSample{7} = ifft(ifftshift(Y_mod));
            end
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: EqSlider_4k
        function EqSlider_4kValueChanged(app, event)
            f_selected = ((app.freq <= 6000 & app.freq > 3000) | (app.freq >= -6000 & app.freq < -3000));  % Frequency band to be filtered
            Y_mod = app.freqSample;
            [m,n] = size(Y_mod); %#ok<ASGLU>
            if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                Y_mod1 = Y_mod(:,1);
                Y_mod2 = Y_mod(:,2);
                Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_4k.Value;
                Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_4k.Value;
                app.equalizerSample{8} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
            end
            
            if (n==1)                                                         % For mono, applies slider value to selected frequency band
                Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_4k.Value;
                app.equalizerSample{8} = ifft(ifftshift(Y_mod));
            end
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: EqSlider_8k
        function EqSlider_8kValueChanged(app, event)
            f_selected = ((app.freq <= 12000 & app.freq > 6000) | (app.freq >= -12000 & app.freq < -6000));  % Frequency band to be filtered
            Y_mod = app.freqSample;
            [m,n] = size(Y_mod); %#ok<ASGLU>
            if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                Y_mod1 = Y_mod(:,1);
                Y_mod2 = Y_mod(:,2);
                Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_8k.Value;
                Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_8k.Value;
                app.equalizerSample{9} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
            end
            
            if (n==1)                                                         % For mono, applies slider value to selected frequency band
                Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_8k.Value;
                app.equalizerSample{9} = ifft(ifftshift(Y_mod));
            end
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: EqSlider_16k
        function EqSlider_16kValueChanged(app, event)
            f_selected = ((app.freq > 12000) | (app.freq < -12000));  % Frequency band to be filtered
            Y_mod = app.freqSample;
            [m,n] = size(Y_mod); %#ok<ASGLU>
            if (n==2)                                                         % For stereo, applies slider value to selected frequency band
                Y_mod1 = Y_mod(:,1);
                Y_mod2 = Y_mod(:,2);
                Y_mod1(f_selected) = app.freqSample(f_selected,1) .* 1.5^app.EqSlider_16k.Value;
                Y_mod2(f_selected) = app.freqSample(f_selected,2) .* 1.5^app.EqSlider_16k.Value;
                app.equalizerSample{10} = [ifft(ifftshift(Y_mod1)),ifft(ifftshift(Y_mod2))];
            end
            
            if (n==1)                                                         % For mono, applies slider value to selected frequency band
                Y_mod(f_selected) = app.freqSample(f_selected) .* 1.5^app.EqSlider_16k.Value;
                app.equalizerSample{10} = ifft(ifftshift(Y_mod));
            end
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: VolumeKnob_System
        function VolumeKnob_SystemValueChanged(app, event)
            if (isplaying(app.player{10}))                              % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: Switch_Direction
        function Switch_DirectionValueChanged(app, event)
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: Switch_Stereo
        function Switch_StereoValueChanged(app, event)
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            f = 260.6256;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            f = 293.6648;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            f = 349.2282;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            f = 391.9954;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            f = 329.6276;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            f = 440;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            f = 493.8833;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: CSharpButton
        function CSharpButtonPushed(app, event)
            f = 277.1826;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: DSharpButton
        function DSharpButtonPushed(app, event)
            f = 311.1270;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: FSharpButton
        function FSharpButtonPushed(app, event)
            f = 369.9944;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: GSharpButton
        function GSharpButtonPushed(app, event)
            f = 415.3047;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: ASharpButton
        function ASharpButtonPushed(app, event)
            f = 466.1638;% Note frequency
            Fs = 44100; %Sampling frequency
            Duration = 0.5; % seconds duration
            t = 0:1/Fs:Duration;
            
            
            switch app.WaveListBox.Value % switch for the different type of wave
                case '1'
                    y = sin(2*pi*f*t);
                    sound(y,Fs);
                case '2'
                    y = sawtooth(2*pi*f*t);
                    sound(y,Fs);
                case '3'
                    y = square(2*pi*f*t);
                    sound(y,Fs);
                otherwise
                    y = sin(2*pi*f*t);
            end
            app.sample{13} = y';
            plot(app.UIAxes19, t(1:1000),y(1:1000)); %plot graph as waveform
        end

        % Button pushed function: SaveSampleButton_Synth
        function SaveSampleButton_SynthPushed(app, event)
            if (isequal(app.sample{13},0))||(isempty(app.sample{13}))
                app.DisplayTextArea.Value = 'No sample created';
                pause(3)
                app.DisplayTextArea.Value = '';                
            else
            app.SaveSample(str2double(app.SaveToDropDown_3.Value),app.sample{13}, 44100);  % Saves sample to desired location
            end
        end

        % Button pushed function: RecordButton_System
        function RecordButton_SystemPushed(app, event)
            app.startRecord = app.player{10}.CurrentSample;      % Gets start record point
        end

        % Button pushed function: StopRecordButton_System
        function StopRecordButton_SystemPushed(app, event)
            app.stopRecord = app.player{10}.CurrentSample;        % Gets stop record point
            if(isequal(app.sample{10},0)) || (isempty(app.sample{10}))
                app.DisplayTextArea.Value = 'No sample loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            elseif ((app.startRecord == 0) || (app.startRecord >= app.stopRecord))   % Handles error if there is no start record location
                app.DisplayTextArea.Value = 'Press record first';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                
                value = str2double(app.SaveToDropDown_2.Value);     % Gets location to store sample
                
                if (app.player{10}.NumChannels == 1)                % For mono, creates audio vector
                    audioSample = app.sample{10}(app.startRecord:app.stopRecord);
                end
                
                if (app.player{10}.NumChannels == 2)                % For stereo, creates audio vector
                    left = app.sample{10}(app.startRecord:app.stopRecord,1);
                    right = app.sample{10}(app.startRecord:app.stopRecord,2);
                    audioSample = [left, right];
                end
                
                app.SaveSample(value, audioSample, app.sampleRate{10}); % Saves sample to desired location
                app.startRecord = 0;                                    % Resets start recording position
                
            end
        end

        % Value changed function: ClipSlider1
        function ClipSlider1ValueChanged(app, event)
            if (isplaying(app.player{10}))                              % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: ClipSlider2
        function ClipSlider2ValueChanged(app, event)
            if (isplaying(app.player{10}))                              % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Button pushed function: SaveSampleButton_System
        function SaveSampleButton_SystemPushed(app, event)
            if (isequal(app.sample{10},0) || isempty(app.sample{10}))
                app.DisplayTextArea.Value = 'No sample loaded';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
            app.SaveSample(str2double(app.SaveToDropDown_2.Value),app.sample{10}, app.sampleRate{10});  % Saves sample to desired location
            end
        end

        % Value changed function: DelaySlider
        function DelaySliderValueChanged(app, event)
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: EchoSlider_2
        function EchoSlider_2ValueChanged(app, event)
            if (isplaying(app.player{10}))                            % Calls back to play function if system is playing when parameter is changed
                app.PlayButton_SystemPushed()
            end
        end

        % Value changed function: Track1Slider
        function Track1SliderValueChanged(app, event)
            if (size(app.permSample{10}) == size(app.sample{12}))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Value changed function: Track2Slider
        function Track2SliderValueChanged(app, event)
            if (size(app.permSample{10}) == size(app.sample{12}))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Value changed function: Track3Slider
        function Track3SliderValueChanged(app, event)
            if (size(app.permSample{10}) == size(app.sample{12}))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Value changed function: Track4Slider
        function Track4SliderValueChanged(app, event)
            if (size(app.permSample{10}) == size(app.sample{12}))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Value changed function: Track5Slider
        function Track5SliderValueChanged(app, event)
            if (size(app.permSample{10}) == size(app.sample{12}))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Value changed function: Track6Slider
        function Track6SliderValueChanged(app, event)
            if (size(app.permSample{10}) == size(app.sample{12}))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Value changed function: Track7Slider
        function Track7SliderValueChanged(app, event)
            if (size(app.permSample{10}) == size(app.sample{12}))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Value changed function: Track8Slider
        function Track8SliderValueChanged(app, event)
            if (size(app.permSample{10}) == size(app.sample{12}))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Value changed function: Track1DropDown
        function Track1DropDownValueChanged(app, event)
            if (strcmpi(app.Track1DropDown.Value, 'Select Sample'))
                app.DisplayTextArea.Value = 'Selected sample is empty';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                value = str2double(app.Track1DropDown.Value);
                if (isequal(app.sample{value},0)||(isempty(app.sample{value})))
                    app.DisplayTextArea.Value = 'Selected sample is empty';
                    pause(3)
                    app.DisplayTextArea.Value = '';
                else
                    if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                        if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                            app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                            pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                            app.pauseFlag(11) = true;
                            app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                        end
                    end
                end
            end
        end

        % Value changed function: Track2DropDown
        function Track2DropDownValueChanged(app, event)
            if (strcmpi(app.Track2DropDown.Value, 'Select Sample'))
                app.DisplayTextArea.Value = 'Selected sample is empty';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                value = str2double(app.Track2DropDown.Value);
                if (isequal(app.sample{value},0)||(isempty(app.sample{value})))
                    app.DisplayTextArea.Value = 'Selected sample is empty';
                    pause(3)
                    app.DisplayTextArea.Value = '';
                else
                    if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                        if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                            app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                            pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                            app.pauseFlag(11) = true;
                            app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                        end
                    end
                end
            end
        end

        % Value changed function: Track3DropDown
        function Track3DropDownValueChanged(app, event)
            if (strcmpi(app.Track3DropDown.Value, 'Select Sample'))
                app.DisplayTextArea.Value = 'Selected sample is empty';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                value = str2double(app.Track3DropDown.Value);
                if (isequal(app.sample{value},0)||(isempty(app.sample{value})))
                    app.DisplayTextArea.Value = 'Selected sample is empty';
                    pause(3)
                    app.DisplayTextArea.Value = '';
                else
                    if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                        if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                            app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                            pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                            app.pauseFlag(11) = true;
                            app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                        end
                    end
                end
            end 
        end

        % Value changed function: Track4DropDown
        function Track4DropDownValueChanged(app, event)
            if (strcmpi(app.Track4DropDown.Value, 'Select Sample'))
                app.DisplayTextArea.Value = 'Selected sample is empty';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                value = str2double(app.Track4DropDown.Value);
                if (isequal(app.sample{value},0)||(isempty(app.sample{value})))
                    app.DisplayTextArea.Value = 'Selected sample is empty';
                    pause(3)
                    app.DisplayTextArea.Value = '';
                else
                    if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                        if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                            app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                            pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                            app.pauseFlag(11) = true;
                            app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                        end
                    end
                end
            end
        end

        % Value changed function: Track5DropDown
        function Track5DropDownValueChanged(app, event)
            if (strcmpi(app.Track5DropDown.Value, 'Select Sample'))
                app.DisplayTextArea.Value = 'Selected sample is empty';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                value = str2double(app.Track5DropDown.Value);
                if (isequal(app.sample{value},0)||(isempty(app.sample{value})))
                    app.DisplayTextArea.Value = 'Selected sample is empty';
                    pause(3)
                    app.DisplayTextArea.Value = '';
                else
                    if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                        if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                            app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                            pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                            app.pauseFlag(11) = true;
                            app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                        end
                    end
                end
            end
        end

        % Value changed function: Track6DropDown
        function Track6DropDownValueChanged(app, event)
            if (strcmpi(app.Track6DropDown.Value, 'Select Sample'))
                app.DisplayTextArea.Value = 'Selected sample is empty';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                value = str2double(app.Track6DropDown.Value);
                if (isequal(app.sample{value},0)||(isempty(app.sample{value})))
                    app.DisplayTextArea.Value = 'Selected sample is empty';
                    pause(3)
                    app.DisplayTextArea.Value = '';
                else
                    if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                        if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                            app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                            pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                            app.pauseFlag(11) = true;
                            app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                        end
                    end
                end
            end
        end

        % Value changed function: Track7DropDown
        function Track7DropDownValueChanged(app, event)
            if (strcmpi(app.Track7DropDown.Value, 'Select Sample'))
                app.DisplayTextArea.Value = 'Selected sample is empty';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                value = str2double(app.Track7DropDown.Value);
                if (isequal(app.sample{value},0)||(isempty(app.sample{value})))
                    app.DisplayTextArea.Value = 'Selected sample is empty';
                    pause(3)
                    app.DisplayTextArea.Value = '';
                else
                    if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                        if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                            app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                            pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                            app.pauseFlag(11) = true;
                            app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                        end
                    end
                end
            end
        end

        % Value changed function: Track8DropDown
        function Track8DropDownValueChanged(app, event)
            if (strcmpi(app.Track8DropDown.Value, 'Select Sample'))
                app.DisplayTextArea.Value = 'Selected sample is empty';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                value = str2double(app.Track8DropDown.Value);
                if (isequal(app.sample{value},0)||(isempty(app.sample{value})))
                    app.DisplayTextArea.Value = 'Selected sample is empty';
                    pause(3)
                    app.DisplayTextArea.Value = '';
                else
                    if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                        if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                            app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                            pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                            app.pauseFlag(11) = true;
                            app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                        end
                    end
                end
            end
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Value changed function: SampleLengthminsEditField
        function SampleLengthminsEditFieldValueChanged(app, event)
            if (isequal(size(app.permSample{10}),size(app.sample{12})))                           % Checks if mixer sample is playing
                if (isequal(app.permSample{10},app.sample{12}) && isplaying(app.player{10}))
                    app.trackLocation = app.player{10}.CurrentSample;                       % Records system position to be replayed from
                    pause(app.player{10});                                                  % Sample pauses in background to allow new audioplayer to be created
                    app.pauseFlag(11) = true;
                    app.PlayButton_MixerPushed();                                           % Calls to mixer play function
                end
            end
        end

        % Button pushed function: RecordButton_Mixer
        function RecordButton_MixerPushed(app, event)
            app.startRecord2 = app.player{10}.CurrentSample;      % Gets start record point
        end

        % Button pushed function: StopRecordButton_Mixer
        function StopRecordButton_MixerPushed(app, event)
            app.stopRecord2 = app.player{10}.CurrentSample;        % Gets stop record point       
            if(isequal(app.sample{12},0)) || (isempty(app.sample{12}))
                app.DisplayTextArea.Value = 'No sample loaded';
                pause(3)
                app.DisplayTextArea.Value = '';            
            elseif ((app.startRecord2 == 0) || (app.startRecord2 >= app.stopRecord2))   % Handles error if there is no start record location
                app.DisplayTextArea.Value = 'Press record first';
                pause(3)
                app.DisplayTextArea.Value = '';
            else
                
                value = str2double(app.SaveToDropDown_Mixer.Value);     % Gets location to store sample
                
                if (app.player{10}.NumChannels == 1)                % For mono, creates audio vector
                    audioSample = app.sample{10}(app.startRecord2:app.stopRecord2);
                end
                
                if (app.player{10}.NumChannels == 2)                % For stereo, creates audio vector
                    left = app.sample{10}(app.startRecord2:app.stopRecord2,1);
                    right = app.sample{10}(app.startRecord2:app.stopRecord2,2);
                    audioSample = [left, right];
                end
                
                app.SaveSample(value, audioSample, app.sampleRate{10}); % Saves sample to desired location
                app.startRecord2 = 0;                                    % Resets start recording position
                
            end
        end

        % Button pushed function: SaveSampleButton_Mixer
        function SaveSampleButton_MixerPushed(app, event)

            if (isequal(app.sample{12},0))         % Checks if mixer audio is playing
                app.DisplayTextArea.Value = 'Sample not playing in mixer';
                pause(3)
                app.DisplayTextArea.Value = '';            
            else
                app.SaveSample(str2double(app.SaveToDropDown_Mixer.Value),app.sample{12}, app.SampleRateEditField.Value);  % Saves sample to desired location   
            end
        end

        % Button pushed function: PauseButton_Mixer
        function PauseButton_MixerPushed(app, event)
            if (isequal(app.sample{12},app.permSample{10})==false)
                app.DisplayTextArea.Value = 'Sample not playing in mixer';
                pause(3)
                app.DisplayTextArea.Value = '';
            elseif (isequal(app.sample{10},0)) || (isempty(app.sample{10}))
                app.DisplayTextArea.Value = 'Sample not playing in mixer';
                pause(3)
                app.DisplayTextArea.Value = '';                         
            else
                pause(app.player{10});
                app.pauseFlag(10) = true;             % Sets pause flag
                app.Lamp_Mixer.Color = 'red';
                app.Lamp_System.Color = 'red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Sample1Panel = uipanel(app.UIFigure);
            app.Sample1Panel.Title = 'Sample 1';
            app.LoadButton_1 = uibutton(app.Sample1Panel, 'push');
            app.LoadButton_1.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_1Pushed, true);
            app.LoadButton_1.Text = 'Load';
            app.PlayButton_1 = uibutton(app.Sample1Panel, 'push');
            app.PlayButton_1.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_1Pushed, true);
            app.PlayButton_1.Text = 'Play';
            app.VolumeKnobLabel = uilabel(app.Sample1Panel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob_1 = uiknob(app.Sample1Panel, 'continuous');
            app.VolumeKnob_1.MajorTicks = [0 25 50 75 100];
            app.VolumeKnob_1.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.VolumeKnob_1.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_1ValueChanged, true);
            app.PauseButton_1 = uibutton(app.Sample1Panel, 'push');
            app.PauseButton_1.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_1Pushed, true);
            app.PauseButton_1.Text = 'Pause';
            app.UIAxes2_1 = uiaxes(app.Sample1Panel);
            title(app.UIAxes2_1, '')
            xlabel(app.UIAxes2_1, '')
            ylabel(app.UIAxes2_1, '')
            app.UIAxes2_1.XTick = [];
            app.UIAxes2_1.XTickLabel = '';
            app.UIAxes2_1.YTick = [];
            app.UIAxes2_1.YTickLabel = '';
            app.Lamp_1 = uilamp(app.Sample1Panel);
            app.Sample2Panel = uipanel(app.UIFigure);
            app.Sample2Panel.Title = 'Sample 2';
            app.LoadButton_2 = uibutton(app.Sample2Panel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.PlayButton_2 = uibutton(app.Sample2Panel, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.VolumeKnob_10Label = uilabel(app.Sample2Panel);
            app.VolumeKnob_10Label.Text = 'Volume';
            app.VolumeKnob_2 = uiknob(app.Sample2Panel, 'continuous');
            app.VolumeKnob_2.MajorTicks = [0 25 50 75 100];
            app.VolumeKnob_2.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.VolumeKnob_2.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_2ValueChanged, true);
            app.PauseButton_2 = uibutton(app.Sample2Panel, 'push');
            app.PauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_2Pushed, true);
            app.PauseButton_2.Text = 'Pause';
            app.UIAxes2_2 = uiaxes(app.Sample2Panel);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.XTickLabel = '';
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_2.YTickLabel = '';
            app.Lamp_2 = uilamp(app.Sample2Panel);
            app.Sample3Panel = uipanel(app.UIFigure);
            app.Sample3Panel.Title = 'Sample 3';
            app.LoadButton_3 = uibutton(app.Sample3Panel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.PlayButton_3 = uibutton(app.Sample3Panel, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Text = 'Play';
            app.VolumeKnob_11Label = uilabel(app.Sample3Panel);
            app.VolumeKnob_11Label.Text = 'Volume';
            app.VolumeKnob_3 = uiknob(app.Sample3Panel, 'continuous');
            app.VolumeKnob_3.MajorTicks = [0 25 50 75 100];
            app.VolumeKnob_3.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.VolumeKnob_3.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_3ValueChanged, true);
            app.PauseButton_3 = uibutton(app.Sample3Panel, 'push');
            app.PauseButton_3.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_3Pushed, true);
            app.PauseButton_3.Text = 'Pause';
            app.UIAxes2_3 = uiaxes(app.Sample3Panel);
            title(app.UIAxes2_3, '')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, '')
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.XTickLabel = '';
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_3.YTickLabel = '';
            app.Lamp_3 = uilamp(app.Sample3Panel);
            app.Sample4Panel = uipanel(app.UIFigure);
            app.Sample4Panel.Title = 'Sample 4';
            app.LoadButton_4 = uibutton(app.Sample4Panel, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_4Pushed, true);
            app.LoadButton_4.Text = 'Load';
            app.PlayButton_4 = uibutton(app.Sample4Panel, 'push');
            app.PlayButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_4Pushed, true);
            app.PlayButton_4.Text = 'Play';
            app.VolumeKnob_12Label = uilabel(app.Sample4Panel);
            app.VolumeKnob_12Label.Text = 'Volume';
            app.VolumeKnob_4 = uiknob(app.Sample4Panel, 'continuous');
            app.VolumeKnob_4.MajorTicks = [0 25 50 75 100];
            app.VolumeKnob_4.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.VolumeKnob_4.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_4ValueChanged, true);
            app.PauseButton_4 = uibutton(app.Sample4Panel, 'push');
            app.PauseButton_4.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_4Pushed, true);
            app.PauseButton_4.Text = 'Pause';
            app.UIAxes2_4 = uiaxes(app.Sample4Panel);
            title(app.UIAxes2_4, '')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, '')
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.XTickLabel = '';
            app.UIAxes2_4.YTick = [];
            app.UIAxes2_4.YTickLabel = '';
            app.Lamp_4 = uilamp(app.Sample4Panel);
            app.Sample5Panel = uipanel(app.UIFigure);
            app.Sample5Panel.Title = 'Sample 5';
            app.LoadButton_5 = uibutton(app.Sample5Panel, 'push');
            app.LoadButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_5Pushed, true);
            app.LoadButton_5.Text = 'Load';
            app.PlayButton_5 = uibutton(app.Sample5Panel, 'push');
            app.PlayButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_5Pushed, true);
            app.PlayButton_5.Text = 'Play';
            app.VolumeKnob_13Label = uilabel(app.Sample5Panel);
            app.VolumeKnob_13Label.Text = 'Volume';
            app.VolumeKnob_5 = uiknob(app.Sample5Panel, 'continuous');
            app.VolumeKnob_5.MajorTicks = [0 25 50 75 100];
            app.VolumeKnob_5.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.VolumeKnob_5.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_5ValueChanged, true);
            app.PauseButton_5 = uibutton(app.Sample5Panel, 'push');
            app.PauseButton_5.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_5Pushed, true);
            app.PauseButton_5.Text = 'Pause';
            app.UIAxes2_5 = uiaxes(app.Sample5Panel);
            title(app.UIAxes2_5, '')
            xlabel(app.UIAxes2_5, '')
            ylabel(app.UIAxes2_5, '')
            app.UIAxes2_5.XTick = [];
            app.UIAxes2_5.XTickLabel = '';
            app.UIAxes2_5.YTick = [];
            app.UIAxes2_5.YTickLabel = '';
            app.Lamp_5 = uilamp(app.Sample5Panel);
            app.Sample6Panel = uipanel(app.UIFigure);
            app.Sample6Panel.Title = 'Sample 6';
            app.LoadButton_6 = uibutton(app.Sample6Panel, 'push');
            app.LoadButton_6.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_6Pushed, true);
            app.LoadButton_6.Text = 'Load';
            app.PlayButton_6 = uibutton(app.Sample6Panel, 'push');
            app.PlayButton_6.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_6Pushed, true);
            app.PlayButton_6.Text = 'Play';
            app.VolumeKnob_14Label = uilabel(app.Sample6Panel);
            app.VolumeKnob_14Label.Text = 'Volume';
            app.VolumeKnob_6 = uiknob(app.Sample6Panel, 'continuous');
            app.VolumeKnob_6.MajorTicks = [0 25 50 75 100];
            app.VolumeKnob_6.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.VolumeKnob_6.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_6ValueChanged, true);
            app.PauseButton_6 = uibutton(app.Sample6Panel, 'push');
            app.PauseButton_6.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_6Pushed, true);
            app.PauseButton_6.Text = 'Pause';
            app.UIAxes2_6 = uiaxes(app.Sample6Panel);
            title(app.UIAxes2_6, '')
            xlabel(app.UIAxes2_6, '')
            ylabel(app.UIAxes2_6, '')
            app.UIAxes2_6.XTick = [];
            app.UIAxes2_6.XTickLabel = '';
            app.UIAxes2_6.YTick = [];
            app.UIAxes2_6.YTickLabel = '';
            app.Lamp_6 = uilamp(app.Sample6Panel);
            app.Sample7Panel = uipanel(app.UIFigure);
            app.Sample7Panel.Title = 'Sample 7';
            app.LoadButton_7 = uibutton(app.Sample7Panel, 'push');
            app.LoadButton_7.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_7Pushed, true);
            app.LoadButton_7.Text = 'Load';
            app.PlayButton_7 = uibutton(app.Sample7Panel, 'push');
            app.PlayButton_7.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_7Pushed, true);
            app.PlayButton_7.Text = 'Play';
            app.VolumeKnob_15Label = uilabel(app.Sample7Panel);
            app.VolumeKnob_15Label.Text = 'Volume';
            app.VolumeKnob_7 = uiknob(app.Sample7Panel, 'continuous');
            app.VolumeKnob_7.MajorTicks = [0 25 50 75 100];
            app.VolumeKnob_7.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.VolumeKnob_7.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_7ValueChanged, true);
            app.PauseButton_7 = uibutton(app.Sample7Panel, 'push');
            app.PauseButton_7.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_7Pushed, true);
            app.PauseButton_7.Text = 'Pause';
            app.UIAxes2_7 = uiaxes(app.Sample7Panel);
            title(app.UIAxes2_7, '')
            xlabel(app.UIAxes2_7, '')
            ylabel(app.UIAxes2_7, '')
            app.UIAxes2_7.XTick = [];
            app.UIAxes2_7.XTickLabel = '';
            app.UIAxes2_7.YTick = [];
            app.UIAxes2_7.YTickLabel = '';
            app.Lamp_7 = uilamp(app.Sample7Panel);
            app.Sample8Panel = uipanel(app.UIFigure);
            app.Sample8Panel.Title = 'Sample 8';
            app.LoadButton_8 = uibutton(app.Sample8Panel, 'push');
            app.LoadButton_8.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_8Pushed, true);
            app.LoadButton_8.Text = 'Load';
            app.PlayButton_8 = uibutton(app.Sample8Panel, 'push');
            app.PlayButton_8.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_8Pushed, true);
            app.PlayButton_8.Text = 'Play';
            app.VolumeKnob_16Label = uilabel(app.Sample8Panel);
            app.VolumeKnob_16Label.Text = 'Volume';
            app.VolumeKnob_8 = uiknob(app.Sample8Panel, 'continuous');
            app.VolumeKnob_8.MajorTicks = [0 25 50 75 100];
            app.VolumeKnob_8.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.VolumeKnob_8.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_8ValueChanged, true);
            app.PauseButton_8 = uibutton(app.Sample8Panel, 'push');
            app.PauseButton_8.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_8Pushed, true);
            app.PauseButton_8.Text = 'Pause';
            app.UIAxes2_8 = uiaxes(app.Sample8Panel);
            title(app.UIAxes2_8, '')
            xlabel(app.UIAxes2_8, '')
            ylabel(app.UIAxes2_8, '')
            app.UIAxes2_8.XTick = [];
            app.UIAxes2_8.XTickLabel = '';
            app.UIAxes2_8.YTick = [];
            app.UIAxes2_8.YTickLabel = '';
            app.Lamp_8 = uilamp(app.Sample8Panel);
            app.Sample9Panel = uipanel(app.UIFigure);
            app.Sample9Panel.Title = 'Sample 9';
            app.LoadButton_9 = uibutton(app.Sample9Panel, 'push');
            app.LoadButton_9.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_9Pushed, true);
            app.LoadButton_9.Text = 'Load';
            app.PlayButton_9 = uibutton(app.Sample9Panel, 'push');
            app.PlayButton_9.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_9Pushed, true);
            app.PlayButton_9.Text = 'Play';
            app.VolumeKnob_17Label = uilabel(app.Sample9Panel);
            app.VolumeKnob_17Label.Text = 'Volume';
            app.VolumeKnob_9 = uiknob(app.Sample9Panel, 'continuous');
            app.VolumeKnob_9.MajorTicks = [0 25 50 75 100];
            app.VolumeKnob_9.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.VolumeKnob_9.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_9ValueChanged, true);
            app.PauseButton_9 = uibutton(app.Sample9Panel, 'push');
            app.PauseButton_9.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_9Pushed, true);
            app.PauseButton_9.Text = 'Pause';
            app.UIAxes2_9 = uiaxes(app.Sample9Panel);
            title(app.UIAxes2_9, '')
            xlabel(app.UIAxes2_9, '')
            ylabel(app.UIAxes2_9, '')
            app.UIAxes2_9.XTick = [];
            app.UIAxes2_9.XTickLabel = '';
            app.UIAxes2_9.YTick = [];
            app.UIAxes2_9.YTickLabel = '';
            app.Lamp_9 = uilamp(app.Sample9Panel);
            app.SystemControlPanel = uipanel(app.UIFigure);
            app.SystemControlPanel.Title = 'System Control';
            app.UIAxes_System = uiaxes(app.SystemControlPanel);
            title(app.UIAxes_System, 'Waveform')
            xlabel(app.UIAxes_System, '')
            ylabel(app.UIAxes_System, 'Amplitude')
            app.PlayButton_System = uibutton(app.SystemControlPanel, 'push');
            app.PlayButton_System.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_SystemPushed, true);
            app.PlayButton_System.Text = 'Play';
            app.PauseButton_System = uibutton(app.SystemControlPanel, 'push');
            app.PauseButton_System.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_SystemPushed, true);
            app.PauseButton_System.Text = 'Pause';
            app.RecordButton_System = uibutton(app.SystemControlPanel, 'push');
            app.RecordButton_System.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_SystemPushed, true);
            app.RecordButton_System.Text = 'Record';
            app.ClipSlider2 = uislider(app.SystemControlPanel);
            app.ClipSlider2.MajorTicks = 100;
            app.ClipSlider2.ValueChangedFcn = createCallbackFcn(app, @ClipSlider2ValueChanged, true);
            app.ClipSlider2.MinorTicks = [];
            app.ClipSlider2.Value = 100;
            app.StopRecordButton_System = uibutton(app.SystemControlPanel, 'push');
            app.StopRecordButton_System.ButtonPushedFcn = createCallbackFcn(app, @StopRecordButton_SystemPushed, true);
            app.StopRecordButton_System.Text = 'Stop Record';
            app.SaveSampleButton_System = uibutton(app.SystemControlPanel, 'push');
            app.SaveSampleButton_System.ButtonPushedFcn = createCallbackFcn(app, @SaveSampleButton_SystemPushed, true);
            app.SaveSampleButton_System.Text = 'Save Sample';
            app.Lamp_System = uilamp(app.SystemControlPanel);
            app.VolumeKnob_18Label = uilabel(app.SystemControlPanel);
            app.VolumeKnob_18Label.Text = 'Volume';
            app.VolumeKnob_System = uiknob(app.SystemControlPanel, 'continuous');
            app.VolumeKnob_System.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_SystemValueChanged, true);
            app.Label = uilabel(app.SystemControlPanel);
            app.Label.Text = '';
            app.Switch_Direction = uiswitch(app.SystemControlPanel, 'toggle');
            app.Switch_Direction.Items = {'Reverse', 'Forward'};
            app.Switch_Direction.ValueChangedFcn = createCallbackFcn(app, @Switch_DirectionValueChanged, true);
            app.Switch_Direction.Value = 'Reverse';
            app.Switch_2Label = uilabel(app.SystemControlPanel);
            app.Switch_2Label.Text = '';
            app.Switch_Stereo = uiswitch(app.SystemControlPanel, 'toggle');
            app.Switch_Stereo.Items = {'Stereo', 'Mono'};
            app.Switch_Stereo.ValueChangedFcn = createCallbackFcn(app, @Switch_StereoValueChanged, true);
            app.Switch_Stereo.Value = 'Stereo';
            app.HzLabel = uilabel(app.SystemControlPanel);
            app.HzLabel.Text = '32 Hz';
            app.EqSlider_32 = uislider(app.SystemControlPanel);
            app.EqSlider_32.Limits = [-10 10];
            app.EqSlider_32.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_32.ValueChangedFcn = createCallbackFcn(app, @EqSlider_32ValueChanged, true);
            app.HzLabel_2 = uilabel(app.SystemControlPanel);
            app.HzLabel_2.Text = '64 Hz';
            app.EqSlider_64 = uislider(app.SystemControlPanel);
            app.EqSlider_64.Limits = [-10 10];
            app.EqSlider_64.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_64.ValueChangedFcn = createCallbackFcn(app, @EqSlider_64ValueChanged, true);
            app.HzLabel_3 = uilabel(app.SystemControlPanel);
            app.HzLabel_3.Text = '125 Hz';
            app.EqSlider_125 = uislider(app.SystemControlPanel);
            app.EqSlider_125.Limits = [-10 10];
            app.EqSlider_125.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_125.ValueChangedFcn = createCallbackFcn(app, @EqSlider_125ValueChanged, true);
            app.HzLabel_4 = uilabel(app.SystemControlPanel);
            app.HzLabel_4.Text = '250 Hz';
            app.EqSlider_250 = uislider(app.SystemControlPanel);
            app.EqSlider_250.Limits = [-10 10];
            app.EqSlider_250.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_250.ValueChangedFcn = createCallbackFcn(app, @EqSlider_250ValueChanged, true);
            app.HzLabel_5 = uilabel(app.SystemControlPanel);
            app.HzLabel_5.Text = '500 Hz';
            app.EqSlider_500 = uislider(app.SystemControlPanel);
            app.EqSlider_500.Limits = [-10 10];
            app.EqSlider_500.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_500.ValueChangedFcn = createCallbackFcn(app, @EqSlider_500ValueChanged, true);
            app.kHzLabel = uilabel(app.SystemControlPanel);
            app.kHzLabel.Text = '1 kHz';
            app.EqSlider_1k = uislider(app.SystemControlPanel);
            app.EqSlider_1k.Limits = [-10 10];
            app.EqSlider_1k.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_1k.ValueChangedFcn = createCallbackFcn(app, @EqSlider_1kValueChanged, true);
            app.kHzLabel_2 = uilabel(app.SystemControlPanel);
            app.kHzLabel_2.Text = '2 kHz';
            app.EqSlider_2k = uislider(app.SystemControlPanel);
            app.EqSlider_2k.Limits = [-10 10];
            app.EqSlider_2k.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_2k.ValueChangedFcn = createCallbackFcn(app, @EqSlider_2kValueChanged, true);
            app.kHzLabel_3 = uilabel(app.SystemControlPanel);
            app.kHzLabel_3.Text = '4 kHz';
            app.EqSlider_4k = uislider(app.SystemControlPanel);
            app.EqSlider_4k.Limits = [-10 10];
            app.EqSlider_4k.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_4k.ValueChangedFcn = createCallbackFcn(app, @EqSlider_4kValueChanged, true);
            app.kHzLabel_4 = uilabel(app.SystemControlPanel);
            app.kHzLabel_4.Text = '8 kHz';
            app.EqSlider_8k = uislider(app.SystemControlPanel);
            app.EqSlider_8k.Limits = [-10 10];
            app.EqSlider_8k.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_8k.ValueChangedFcn = createCallbackFcn(app, @EqSlider_8kValueChanged, true);
            app.kHzLabel_5 = uilabel(app.SystemControlPanel);
            app.kHzLabel_5.Text = '16 kHz';
            app.EqSlider_16k = uislider(app.SystemControlPanel);
            app.EqSlider_16k.Limits = [-10 10];
            app.EqSlider_16k.MajorTicks = [-10 -5 0 5 10];
            app.EqSlider_16k.ValueChangedFcn = createCallbackFcn(app, @EqSlider_16kValueChanged, true);
            app.LoadSampleDropDownLabel = uilabel(app.SystemControlPanel);
            app.LoadSampleDropDownLabel.Text = 'Load Sample';
            app.LoadSampleDropDown = uidropdown(app.SystemControlPanel);
            app.LoadSampleDropDown.Items = {'Select Sample', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.LoadSampleDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadSampleDropDownValueChanged, true);
            app.LoadSampleDropDown.Value = 'Select Sample';
            app.SaveToDropDown_2Label = uilabel(app.SystemControlPanel);
            app.SaveToDropDown_2Label.Text = 'Save To:';
            app.SaveToDropDown_2 = uidropdown(app.SystemControlPanel);
            app.SaveToDropDown_2.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SaveToDropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SaveToDropDown_2.Value = '1';
            app.SpeedSliderLabel = uilabel(app.SystemControlPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.SystemControlPanel);
            app.SpeedSlider.Limits = [0.25 2.5];
            app.SpeedSlider.MajorTicks = [0.25 0.5 0.75 1 1.5 2 2.5];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Value = 0.25;
            app.DelaySliderLabel = uilabel(app.SystemControlPanel);
            app.DelaySliderLabel.Text = 'Delay';
            app.DelaySlider = uislider(app.SystemControlPanel);
            app.DelaySlider.Limits = [1 10];
            app.DelaySlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.DelaySlider.ValueChangedFcn = createCallbackFcn(app, @DelaySliderValueChanged, true);
            app.DelaySlider.Value = 1;
            app.ClipSliderLabel = uilabel(app.SystemControlPanel);
            app.ClipSliderLabel.Text = 'Clip';
            app.ClipSlider1 = uislider(app.SystemControlPanel);
            app.ClipSlider1.MajorTicks = 0;
            app.ClipSlider1.ValueChangedFcn = createCallbackFcn(app, @ClipSlider1ValueChanged, true);
            app.ClipSlider1.MinorTicks = [];
            app.EchoSlider_2Label = uilabel(app.SystemControlPanel);
            app.EchoSlider_2Label.Text = 'Echo';
            app.EchoSlider_2 = uislider(app.SystemControlPanel);
            app.EchoSlider_2.Limits = [0 10];
            app.EchoSlider_2.ValueChangedFcn = createCallbackFcn(app, @EchoSlider_2ValueChanged, true);
            app.DisplayTextAreaLabel = uilabel(app.SystemControlPanel);
            app.DisplayTextAreaLabel.Text = 'Display';
            app.DisplayTextArea = uitextarea(app.SystemControlPanel);
            app.MixingPanel = uipanel(app.UIFigure);
            app.MixingPanel.Title = 'Mixing Panel';
            app.Track1Slider = uislider(app.MixingPanel);
            app.Track1Slider.MajorTicks = [];
            app.Track1Slider.ValueChangedFcn = createCallbackFcn(app, @Track1SliderValueChanged, true);
            app.Track1Slider.MinorTicks = [];
            app.Track2Slider = uislider(app.MixingPanel);
            app.Track2Slider.MajorTicks = [];
            app.Track2Slider.ValueChangedFcn = createCallbackFcn(app, @Track2SliderValueChanged, true);
            app.Track2Slider.MinorTicks = [];
            app.Track3Slider = uislider(app.MixingPanel);
            app.Track3Slider.MajorTicks = [];
            app.Track3Slider.ValueChangedFcn = createCallbackFcn(app, @Track3SliderValueChanged, true);
            app.Track3Slider.MinorTicks = [];
            app.Track4Slider = uislider(app.MixingPanel);
            app.Track4Slider.MajorTicks = [];
            app.Track4Slider.ValueChangedFcn = createCallbackFcn(app, @Track4SliderValueChanged, true);
            app.Track4Slider.MinorTicks = [];
            app.Track5Slider = uislider(app.MixingPanel);
            app.Track5Slider.MajorTicks = [];
            app.Track5Slider.ValueChangedFcn = createCallbackFcn(app, @Track5SliderValueChanged, true);
            app.Track5Slider.MinorTicks = [];
            app.Track6Slider = uislider(app.MixingPanel);
            app.Track6Slider.MajorTicks = [];
            app.Track6Slider.ValueChangedFcn = createCallbackFcn(app, @Track6SliderValueChanged, true);
            app.Track6Slider.MinorTicks = [];
            app.Track7Slider = uislider(app.MixingPanel);
            app.Track7Slider.MajorTicks = [];
            app.Track7Slider.ValueChangedFcn = createCallbackFcn(app, @Track7SliderValueChanged, true);
            app.Track7Slider.MinorTicks = [];
            app.Track8Slider = uislider(app.MixingPanel);
            app.Track8Slider.MajorTicks = [];
            app.Track8Slider.ValueChangedFcn = createCallbackFcn(app, @Track8SliderValueChanged, true);
            app.Track8Slider.MinorTicks = [];
            app.Track1DropDownLabel = uilabel(app.MixingPanel);
            app.Track1DropDownLabel.Text = 'Track 1';
            app.Track1DropDown = uidropdown(app.MixingPanel);
            app.Track1DropDown.Items = {'Select Sample', 'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.Track1DropDown.ItemsData = {'Select Sample', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Track1DropDown.ValueChangedFcn = createCallbackFcn(app, @Track1DropDownValueChanged, true);
            app.Track1DropDown.Value = '1';
            app.Track2DropDownLabel = uilabel(app.MixingPanel);
            app.Track2DropDownLabel.Text = 'Track 2';
            app.Track2DropDown = uidropdown(app.MixingPanel);
            app.Track2DropDown.Items = {'Select Sample', 'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.Track2DropDown.ItemsData = {'Select Sample', '1', '2', '3', '4', '5', '6', '7', '8', '9', '', ''};
            app.Track2DropDown.ValueChangedFcn = createCallbackFcn(app, @Track2DropDownValueChanged, true);
            app.Track2DropDown.Value = '1';
            app.Track3DropDownLabel = uilabel(app.MixingPanel);
            app.Track3DropDownLabel.Text = 'Track 3';
            app.Track3DropDown = uidropdown(app.MixingPanel);
            app.Track3DropDown.Items = {'Select Sample', 'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.Track3DropDown.ItemsData = {'Select Sample', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Track3DropDown.ValueChangedFcn = createCallbackFcn(app, @Track3DropDownValueChanged, true);
            app.Track3DropDown.Value = '1';
            app.Track4DropDownLabel = uilabel(app.MixingPanel);
            app.Track4DropDownLabel.Text = 'Track 4';
            app.Track4DropDown = uidropdown(app.MixingPanel);
            app.Track4DropDown.Items = {'Select Sample', 'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.Track4DropDown.ItemsData = {'Select Sample', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Track4DropDown.ValueChangedFcn = createCallbackFcn(app, @Track4DropDownValueChanged, true);
            app.Track4DropDown.Value = '1';
            app.Track5DropDownLabel = uilabel(app.MixingPanel);
            app.Track5DropDownLabel.Text = 'Track 5';
            app.Track5DropDown = uidropdown(app.MixingPanel);
            app.Track5DropDown.Items = {'Select Sample', 'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.Track5DropDown.ItemsData = {'Select Sample', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Track5DropDown.ValueChangedFcn = createCallbackFcn(app, @Track5DropDownValueChanged, true);
            app.Track5DropDown.Value = '1';
            app.Track6DropDownLabel = uilabel(app.MixingPanel);
            app.Track6DropDownLabel.Text = 'Track 6';
            app.Track6DropDown = uidropdown(app.MixingPanel);
            app.Track6DropDown.Items = {'Select Sample', 'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.Track6DropDown.ItemsData = {'Select Sample', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Track6DropDown.ValueChangedFcn = createCallbackFcn(app, @Track6DropDownValueChanged, true);
            app.Track6DropDown.Value = '1';
            app.Track7DropDownLabel = uilabel(app.MixingPanel);
            app.Track7DropDownLabel.Text = 'Track 7';
            app.Track7DropDown = uidropdown(app.MixingPanel);
            app.Track7DropDown.Items = {'Select Sample', 'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.Track7DropDown.ItemsData = {'Select Sample', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Track7DropDown.ValueChangedFcn = createCallbackFcn(app, @Track7DropDownValueChanged, true);
            app.Track7DropDown.Value = '1';
            app.Track8DropDownLabel = uilabel(app.MixingPanel);
            app.Track8DropDownLabel.Text = 'Track 8';
            app.Track8DropDown = uidropdown(app.MixingPanel);
            app.Track8DropDown.Items = {'Select Sample', 'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.Track8DropDown.ItemsData = {'Select Sample', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Track8DropDown.ValueChangedFcn = createCallbackFcn(app, @Track8DropDownValueChanged, true);
            app.Track8DropDown.Value = '1';
            app.UIAxes = uiaxes(app.MixingPanel);
            title(app.UIAxes, 'Mixed Track')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Amplitude')
            app.PlayButton_Mixer = uibutton(app.MixingPanel, 'push');
            app.PlayButton_Mixer.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_MixerPushed, true);
            app.PlayButton_Mixer.Text = 'Play';
            app.PauseButton_Mixer = uibutton(app.MixingPanel, 'push');
            app.PauseButton_Mixer.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_MixerPushed, true);
            app.PauseButton_Mixer.Text = 'Pause';
            app.RecordButton_Mixer = uibutton(app.MixingPanel, 'push');
            app.RecordButton_Mixer.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_MixerPushed, true);
            app.RecordButton_Mixer.Text = 'Record';
            app.SaveToDropDownLabel = uilabel(app.MixingPanel);
            app.SaveToDropDownLabel.Text = 'Save To:';
            app.SaveToDropDown_Mixer = uidropdown(app.MixingPanel);
            app.SaveToDropDown_Mixer.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SaveToDropDown_Mixer.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SaveToDropDown_Mixer.Value = '1';
            app.Lamp_Mixer = uilamp(app.MixingPanel);
            app.StopRecordButton_Mixer = uibutton(app.MixingPanel, 'push');
            app.StopRecordButton_Mixer.ButtonPushedFcn = createCallbackFcn(app, @StopRecordButton_MixerPushed, true);
            app.StopRecordButton_Mixer.Text = 'Stop Record';
            app.SaveSampleButton_Mixer = uibutton(app.MixingPanel, 'push');
            app.SaveSampleButton_Mixer.ButtonPushedFcn = createCallbackFcn(app, @SaveSampleButton_MixerPushed, true);
            app.SaveSampleButton_Mixer.Text = 'Save Sample';
            app.SampleLengthminsEditFieldLabel = uilabel(app.MixingPanel);
            app.SampleLengthminsEditFieldLabel.Text = 'Sample Length (mins)';
            app.SampleLengthminsEditField = uieditfield(app.MixingPanel, 'numeric');
            app.SampleLengthminsEditField.ValueChangedFcn = createCallbackFcn(app, @SampleLengthminsEditFieldValueChanged, true);
            app.SampleRateEditFieldLabel = uilabel(app.MixingPanel);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.MixingPanel, 'numeric');
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.SynthesizerPanel = uipanel(app.UIFigure);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.UIAxes19 = uiaxes(app.SynthesizerPanel);
            title(app.UIAxes19, 'Waveform')
            xlabel(app.UIAxes19, 'Time')
            ylabel(app.UIAxes19, 'Amplitude')
            app.WaveListBoxLabel = uilabel(app.SynthesizerPanel);
            app.WaveListBoxLabel.Text = 'Wave ';
            app.WaveListBox = uilistbox(app.SynthesizerPanel);
            app.WaveListBox.Items = {'Sine ', 'Sawtooth', 'Square'};
            app.WaveListBox.ItemsData = {'1', '2', '3'};
            app.WaveListBox.Value = '1';
            app.SaveToDropDown_3Label = uilabel(app.SynthesizerPanel);
            app.SaveToDropDown_3Label.Text = 'Save To:';
            app.SaveToDropDown_3 = uidropdown(app.SynthesizerPanel);
            app.SaveToDropDown_3.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SaveToDropDown_3.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SaveToDropDown_3.Value = '1';
            app.CButton = uibutton(app.SynthesizerPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.SynthesizerPanel, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.FButton = uibutton(app.SynthesizerPanel, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.SynthesizerPanel, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.EButton = uibutton(app.SynthesizerPanel, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.AButton = uibutton(app.SynthesizerPanel, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.SynthesizerPanel, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.CSharpButton = uibutton(app.SynthesizerPanel, 'push');
            app.CSharpButton.ButtonPushedFcn = createCallbackFcn(app, @CSharpButtonPushed, true);
            app.CSharpButton.Text = '';
            app.DSharpButton = uibutton(app.SynthesizerPanel, 'push');
            app.DSharpButton.ButtonPushedFcn = createCallbackFcn(app, @DSharpButtonPushed, true);
            app.DSharpButton.Text = '';
            app.FSharpButton = uibutton(app.SynthesizerPanel, 'push');
            app.FSharpButton.ButtonPushedFcn = createCallbackFcn(app, @FSharpButtonPushed, true);
            app.FSharpButton.Text = '';
            app.GSharpButton = uibutton(app.SynthesizerPanel, 'push');
            app.GSharpButton.ButtonPushedFcn = createCallbackFcn(app, @GSharpButtonPushed, true);
            app.GSharpButton.Text = '';
            app.ASharpButton = uibutton(app.SynthesizerPanel, 'push');
            app.ASharpButton.ButtonPushedFcn = createCallbackFcn(app, @ASharpButtonPushed, true);
            app.ASharpButton.Text = '';
            app.SaveSampleButton_Synth = uibutton(app.SynthesizerPanel, 'push');
            app.SaveSampleButton_Synth.ButtonPushedFcn = createCallbackFcn(app, @SaveSampleButton_SynthPushed, true);
            app.SaveSampleButton_Synth.Text = 'Save Sample';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MasterdocFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end